classdef GRN_modeler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        InputOutputMenu           matlab.ui.container.Menu
        SaveMenu                  matlab.ui.container.Menu
        LoadMenu                  matlab.ui.container.Menu
        ExportCodeMenu            matlab.ui.container.Menu
        LoadexternalmodelMenu     matlab.ui.container.Menu
        SimulationMenu            matlab.ui.container.Menu
        SettingsMenu              matlab.ui.container.Menu
        RunMenu                   matlab.ui.container.Menu
        OpeninSimBiologyMenu      matlab.ui.container.Menu
        OpeninCOPASIMenu          matlab.ui.container.Menu
        SpatialsimulationMenu     matlab.ui.container.Menu
        ModelMenu                 matlab.ui.container.Menu
        SelectthemodelMenu        matlab.ui.container.Menu
        ExportSBMLMenu            matlab.ui.container.Menu
        SettingsMenu_2            matlab.ui.container.Menu
        GraphsettingsMenu         matlab.ui.container.Menu
        AddrulesparametersButton  matlab.ui.control.Button
        ProteasePropertiesPanel   matlab.ui.container.Panel
        protease_type             matlab.ui.control.DropDown
        TypeDropDownLabel_3       matlab.ui.control.Label
        Delete_protease           matlab.ui.control.Button
        Set_protease              matlab.ui.control.Button
        Add_protease              matlab.ui.control.Button
        proteased_name            matlab.ui.control.DropDown
        RegulatedLabel_2          matlab.ui.control.Label
        protease_name             matlab.ui.control.EditField
        ProteaseNameLabel         matlab.ui.control.Label
        RegulatorPropertiesPanel  matlab.ui.container.Panel
        regulated_input           matlab.ui.control.DropDown
        RegulatedLabel_3          matlab.ui.control.Label
        regulator_list            matlab.ui.control.DropDown
        Delete_regulator          matlab.ui.control.Button
        Set_regulated             matlab.ui.control.Button
        Add_regulator             matlab.ui.control.Button
        regulated_name            matlab.ui.control.DropDown
        RegulatedLabel            matlab.ui.control.Label
        regulated_type            matlab.ui.control.DropDown
        TypeDropDownLabel         matlab.ui.control.Label
        regulator_name            matlab.ui.control.EditField
        RegulatorLabel            matlab.ui.control.Label
        NodePropertiesPanel       matlab.ui.container.Panel
        node_type                 matlab.ui.control.DropDown
        TypeDropDownLabel_2       matlab.ui.control.Label
        Delete_node               matlab.ui.control.Button
        Set_node                  matlab.ui.control.Button
        Add_node                  matlab.ui.control.Button
        node_name                 matlab.ui.control.EditField
        NodeNameLabel             matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        ContextMenu               matlab.ui.container.ContextMenu
        LayoutMenu                matlab.ui.container.Menu
        circleMenu                matlab.ui.container.Menu
        forceMenu                 matlab.ui.container.Menu
        layeredMenu               matlab.ui.container.Menu
        subspaceMenu              matlab.ui.container.Menu
        force3Menu                matlab.ui.container.Menu
        subspace3Menu             matlab.ui.container.Menu
    end


    properties (Access = private)
        Ecoli Cell; % the model of the system
        log (:,1) cell; % log of the commands
        apphandle struct; % handles for the sub applications
        selected_model (1,:) char; % Description
        solver (1,:) char; % name of the selected solver
    end

    methods (Access = public)

        % update the model
        function [] = update_Ecoli(app,Ecoli)
            app.Ecoli = Ecoli;
        end
        % update and evaluate the log file
        function [] = update_eval_log(app,log)
            for i = 1:numel(log)
                eval(log{i});
            end
            app.update_log(log);
        end
        % update the log file
        function [] = update_log(app,log)
            app.log = [app.log;log];
        end
        % set the log file
        function [] = set_log(app,log)
            app.log = log;
            % delete the '%% Run simulation' part
            app.log_run_delete();
        end
        % set the selected model
        function [] = set_model(app,modelname)
            app.selected_model = modelname;
        end

        % rebuild the system baesed on the log file
        function [] = rebuild_system(app)
            % get the default properties
            app.Ecoli.data.set_properties(SharedData);
            % rebuild from the log file
            for i = 1:numel(app.log)
                eval(app.log{i})
            end
            % set the lists based on the model
            app.setlists();
            % update graph
            app.update_graph();
        end

        % update graph
        function update_graph(app)
            % redraw graph
            app.Ecoli.make_graph(app.UIAxes);
            % set up ButtonDownFcn so we can click on the nodes
            graph = get(app.UIAxes,'Children');
            set(graph,'ButtonDownFcn',@app.ButtonDown_node_selection)
        end
    end
    
    methods (Access = private)

        % save layout to the common graphical settings
        function [] = save_layout(~,layout_type)
            % find the correct folder
            [folderPath, ~, ~] = fileparts(which('graph_settings'));
            if ispc % Win
                folderPath  = [folderPath '\'];
            else % linux, mac
                folderPath  = [folderPath '/'];
            end
            save([folderPath,'graph_settings.mat'],'-append','layout_type');
        end

        % set the lists based on the model
        function [] = setlists(app)
            % nodes
            node_names = fieldnames(app.Ecoli.nodes);
            % proteases
            protease_names = fieldnames(app.Ecoli.proteases);

            % === protease list names ===
            app.proteased_name.Items = node_names;

            % === regulated list: nodes, proteases ===
            app.regulated_name.Items = [node_names;protease_names];
            app.regulated_name.ItemsData = [node_names;protease_names];

            % regulator routes
            regrouts = app.Ecoli.collect_regrouts();
            % set the list
            for i = 1:numel(regrouts)
                % add it to the regulated list
                app.regulated_name.ItemsData{end+1} = regrouts{i};
                if numel(regrouts{i}) == 1
                    app.regulated_name.Items{end+1} = [regrouts{i}{:}];
                else
                    % the regulator object
                    regulator_obj = eval(strrep(app.Ecoli.route2fieldnames(regrouts{i}),'cell.','app.Ecoli.'));
                    % in the Items (what we can see in the app)
                    % the simplified suffix will be present
                    app.regulated_name.Items{end+1} = [regulator_obj.suffix_simple{:}];
                end
            end

            % === regulator list ===
            app.regulator_list.Items =  {''};
            % node
            for i = 1:numel(node_names)
                for node_prefix = app.Ecoli.nodes.(node_names{i}).Mobj.UserData.output_prefix
                    node_reg_name = [node_prefix{1},node_names{i}];
                    if ~any(strcmp(app.regulator_list.Items,node_reg_name))
                        app.regulator_list.Items{end+1} = node_reg_name;
                    end
                end
            end
            % protease
            if ~isempty(protease_names)
                app.regulator_list.Items(end+1:end+numel(protease_names)) = protease_names;
            end
            % regulators
            reg_names = fieldnames(app.Ecoli.data.regulator_routes);
            for i = 1:numel(reg_names)
                regname = reg_names{i};
                if ~any(strcmp(app.regulator_list.Items,regname))
                    app.regulator_list.Items{end+1} = regname;
                end
            end

            % set the regulated type list
            app.regulated_type.Items = fieldnames(app.Ecoli.data.regulator_models);
            % set node type list
            app.node_type.Items = fieldnames(app.Ecoli.data.node_models);
            % set protease type list
            app.protease_type.Items = fieldnames(app.Ecoli.data.protease_models);

            % to change other regulated parameters (input)
            app.regulated_nameValueChanged();

            % update graph
            app.update_graph();

        end
        
        % update regulator and regulated list
        function [] = addname2reglist(app,name)

            % let name be a cell (even if it is just a character array)
            if ~iscell(name)
                name = {name};
            end

            % === regulated list ===
            on_regulated_list = false;
            % check the regulated list whether the new regulated object is
            % already there
            for i = 1:numel(app.regulated_name.ItemsData)
                if numel(app.regulated_name.ItemsData{i})==numel(name) &&...
                    all(strcmp(app.regulated_name.ItemsData{i},name))
                        on_regulated_list = true;
                    break;
                end
            end
            % if it is not on the list already, add it
            if on_regulated_list == false
                app.regulated_name.ItemsData{end+1} = name;
                if numel(name) == 1
                    app.regulated_name.Items{end+1} = [name{:}];
                else
                    % the regulator object
                    regulator_obj = eval(strrep(app.Ecoli.route2fieldnames(name),'cell.','app.Ecoli.'));
                    % in the Items (what we can see in the app)
                    % the simplified suffix will be present
                    app.regulated_name.Items{end+1} = [regulator_obj.suffix_simple{:}];
                end
            end

            % === regulator list ===
            name = name{end};
            % if it is a node
            % go through on every prefix of the node
            if app.Ecoli.isNode(name)
                for node_prefix = app.Ecoli.nodes.(name).Mobj.UserData.output_prefix
                    node_reg_name = [node_prefix{1},name];
                    if ~any(strcmp(app.regulator_list.Items,node_reg_name))
                        app.regulator_list.Items{end+1} = node_reg_name;
                    end
                end
            else
                if ~any(strcmp(app.regulator_list.Items,name))
                    app.regulator_list.Items{end+1} = name;
                end
            end
        end

        % update protease list
        function [] = addname2protlist(app,name)
            if ~any(strcmp(app.proteased_name.Items,name))
                app.proteased_name.Items{end+1} = name;
            end
        end

        % delete the regulators of the object recursively
        function [] = delete_regulator_chain(app,selected_object)
            % go through on every regulated input
            inputs = fieldnames(selected_object.regulators);
            for i = 1:numel(inputs)
                % go through on every regulator of the given input
                regulators = fieldnames(selected_object.regulators.(inputs{i}));
                for j = 1:numel(regulators)

                    % the regulator object
                    regulator = selected_object.regulators.(inputs{i}).(regulators{j});
                    % delete the regulators of the regulator
                    app.delete_regulator_chain(regulator);

                    % delete the regulator
                    % regulation route
                    regrout = regulator.suffix;
                    regrout(strcmp(regrout,'<-')|strcmp(regrout,'|-')) = [];
                    regrout = strcat(',''',regrout,'''');

                    % generate the corresponding log line
                    log_line = ['app.Ecoli = app.Ecoli.add_regulator(''' regulator.type ''''...
                        [regrout{:}] ');'];

                    % find this line in the log and delete it
                    app.log(strcmp(app.log,log_line)) = [];
                    % delete all relating settings
                    app.delete_set(regulator.suffix);
                end
            end
        end

        % delete every protease-node interaction of a protease
        function [] = delete_protease_interactions(app,selected_object)
            % go through on every node
            for i = 1:numel(selected_object.node_list)
                node_name_act = selected_object.node_list{i};
                % delete the protease-node interaction
                log_line = ['app.Ecoli = app.Ecoli.add_protease('''...
                    node_name_act ''',''' selected_object.name ''',''' selected_object.type ''');'];
                % find this line in the log and delete it
                app.log(strcmp(app.log,log_line)) = [];
                % delete all relating settings
                app.delete_set(selected_object.name);
            end
            % rebuild the system from the log file
            app.rebuild_system();
        end

        % delete settings
        function [] = delete_set(app,obj_name)

            % delete the logged state if it ends with the actual
            if ~iscell(obj_name)
                % delete the species connected to a node or protease which
                % name is a character array
                log_line = 'app.Ecoli.data.StatesToLog =' + wildcardPattern + obj_name + '''}];';
                app.log(matches(app.log,log_line)) = [];
            end

            % object name, or route to the object
            obj_name = strcat(',''',obj_name,'''');
            if iscell(obj_name)
                obj_name = [obj_name{:}];
            end
            % pattern for any string which does not contain commas
            w = wildcardPattern('except',',');
            % pattern for the line what we want to delete
            % at the beginnin and at the and we have an extra + w
            % because in the model changing we have comments
            log_line = w + 'app.Ecoli.set(''' + w + ''',''' + w + ''',' + w +  obj_name + ');' + w;
            % delete the lines in the log which contains this pattern
            app.log(matches(app.log,log_line)) = [];

        end

        % get the object of the node what we want to set or delete
        function selected_object = select_node(app)
            object_name = app.node_name.Value;
            % if the object name is not set, we will use the last added
            % object
            if isempty(object_name)
                names = fieldnames(app.Ecoli.nodes);
                if isempty(names)
                    object_name = [];
                else
                    object_name = names{end};
                end
            end
            if app.Ecoli.isNode(object_name)
                selected_object = app.Ecoli.nodes.(object_name);
            else
                selected_object = [];
            end
        end

        % get the object of the protease what we want to set or delete
        function selected_object = select_protease(app)
            object_name = app.protease_name.Value;
            % if the object name is not set, we will use the last added
            % object
            if isempty(object_name)
                names = fieldnames(app.Ecoli.proteases);
                if isempty(names)
                    object_name = [];
                else
                    object_name = names{end};
                end
            end
            if app.Ecoli.isProtease(object_name)
                selected_object = app.Ecoli.proteases.(object_name);
            else
                selected_object = [];
            end
            
        end

        % get the object of the protease what we want to set or delete
        function selected_object = select_regulated(app)
            object_name = app.regulated_name.Value;
            % if the object name is not set, we will use the last added
            % object
            if isempty(object_name)
                names = app.regulated_name.ItemsData;
                if isempty(names)
                    object_name = [];
                else
                    object_name = names{end};
                end
            end
            regroute = strrep(app.Ecoli.route2fieldnames(object_name),'cell.','app.Ecoli.');
            try
                selected_object = eval(regroute);
            catch
                selected_object = [];
            end
        end

        % Function to clear all instances of a class from the base workspace
        function clearClasses(~,className)
            % Get information about variables in the base workspace
            vars = evalin('base', 'whos'); 
            vars = {vars.name};
            for i = 1:length(vars)
                if  evalin('base',['isa(' vars{i} ',''' className ''')']) 
                    evalin('base', sprintf('clear %s;', vars{i})); % Clear the variable
                end
            end
        end

        % find the 'clicked' node in the graph and set its properties in
        % the lists
        function ButtonDown_node_selection(app,handle,event)

            % we have to click on a node, not on a line
            if strcmp(handle.Type,'line')
                return;
            end

            % find our graph node
            distance = rowfun(@norm,table(event.IntersectionPoint(1:2)-[handle.XData;handle.YData].'));
            distance = distance.Var1;
            pos = find(min(distance)==distance,1,'first');
            name = handle.NodeLabel{pos};

            % set it in the regulator list (if it is on it)
            if any(strcmp(app.regulated_name.Items,name))
                app.regulated_name.Value = app.regulated_name.ItemsData{strcmp(app.regulated_name.Items,name)};
                app.regulated_nameValueChanged();
            else
                % if it is not on the regulator list, we do not do anything
                return;
            end

            % if it is a node add it to the protease list
            % and set it as the actual node
            if app.Ecoli.isNode(name)
                app.proteased_name.Value = name;
                app.node_name.Value = name;
            elseif app.Ecoli.isProtease(name)
                % if it is a protease, set it as the actual protease
                app.protease_name.Value = name;
            end


            % get the graph
            graph = get(app.UIAxes,'Children');
            type = get(graph,'type');
            graph = graph(strcmp('graphplot',type));
            % previously selected should get back its original color
            black_node = all(graph.NodeColor==0,2);
            % if we have a black one, we will recolor it
            if any(black_node)
                prev = app.Ecoli.data.G.Nodes.Name{black_node};
                if app.Ecoli.isNode(prev)
                    highlight(graph,prev,'NodeColor',app.Ecoli.data.node_color)
                elseif app.Ecoli.isProtease(prev)
                    highlight(graph,prev,'NodeColor',app.Ecoli.data.protease_color)
                else
                    highlight(graph,prev,'NodeColor',app.Ecoli.data.regulation_color)
                end
            end
            % highlight the selected element
            highlight(graph,name,'NodeColor','black')
            

            % for reg_name = app.regulated_name.Items
            %     if strcmp(name,reg_name)
            %         app.regulated_name.Value = reg_name;
            %         app.regulated_nameValueChanged();
            %         break;
            %     end
            % end

        end

        % change model
        function model_change(app,model_name)
            if ~strcmp(app.Ecoli.data.model_name,model_name)
                % create the cell with the new model
                app.log{contains(app.log,'app.Ecoli = Cell(')} = ['app.Ecoli = Cell(''' model_name ''');'];
                
                for i = 1:numel(app.log)
                    % comment the settings with the different model
                    if app.log{i}(1)~='%' && any(contains(app.log{i},'app.Ecoli.set('))
                        if any(contains(app.log{i},app.Ecoli.data.model_name))
                            % the model name is already there in a comment
                            app.log{i} = ['% ' app.log{i}];
                        else
                            % write the model name in a comment
                            % so we can activate the setting if this model
                            % is selected again
                            app.log{i} = ['% ' app.log{i} ' % ' app.Ecoli.data.model_name];
                        end
                    end
                    % uncomment the settings with the same model
                    if app.log{i}(1)=='%' && any(contains(app.log{i},'app.Ecoli.set(')) && any(contains(app.log{i},model_name))
                        app.log{i} = app.log{i}(3:end);
                    end
                end
                app.rebuild_system();
            end
        end

        % if the log contained '%% Run simulation' part, delete it
        function log_run_delete(app)
            for i = 1:numel(app.log)
                if strcmp(app.log{i}(1:2),'%%')
                    app.log(i:end) = [];
                    return;
                end
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % movethe app in the middle of the screen
            % movegui(app.UIFigure,'center');

            % add current folder and subfolders to the search path
            addpath(genpath(pwd))

            clc
            % To reinitialize the constant property, clear all instances 
            % of the class and then clear the class
            % delete every "bacteria" class variable
            app.clearClasses('bacteria')
            clean_up_GRN

            % default model:
            app.selected_model = 'Tomazou';
            % select new model and set up the lists
            app.UIFigure.Visible = 'off';
            app.SelectthemodelMenuSelected(app.selected_model);
            app.UIFigure.Visible = 'on';
            % if the application was closed in the meantime
            if ~isvalid(app)
                return;
            end
            
            % create cell
            app.Ecoli = Cell(app.selected_model);

            % default simulation settings
            app.solver = 'ode15s';
            set(get(getconfigset(app.Ecoli.data.Mobj),'SolverOptions'),'AbsoluteTolerance',1e-8);
            set(get(getconfigset(app.Ecoli.data.Mobj),'SolverOptions'),'RelativeTolerance',1e-6);
            set(getconfigset(app.Ecoli.data.Mobj),'MaximumWallClock',10);

            % log
            app.log{end+1} = 'clean_up_GRN';
            app.log{end+1} = ['app.Ecoli = Cell(''' app.selected_model ''');'];
            app.log{end+1} = 'app.solver = ''ode15s'';';
            app.log{end+1} = 'set(get(getconfigset(app.Ecoli.data.Mobj),''SolverOptions''),''AbsoluteTolerance'',1e-8)';
            app.log{end+1} = 'set(get(getconfigset(app.Ecoli.data.Mobj),''SolverOptions''),''RelativeTolerance'',1e-6)';
            app.log{end+1} = 'set(getconfigset(app.Ecoli.data.Mobj),''MaximumWallClock'',10)';

            % sub application handles
            app.apphandle(1).export_code = [];
            app.apphandle(1).set_object = [];
            app.apphandle(1).set_simulation = [];
            app.apphandle(1).run_simulation = [];
            app.apphandle(1).model_selection = [];
            app.apphandle(1).export_SBML = [];
            app.apphandle(1).add_rule_param = [];
            app.apphandle(1).set_object = [];
            app.apphandle(1).graph_settings = [];
            app.apphandle(1).spatial_sim  = [];

            % set the lists based on the model
            app.setlists();

        end

        % Menu selected function: LayoutMenu
        function LayoutMenuSelected(app, event)

        end

        % Menu selected function: circleMenu
        function circleMenuSelected(app, event)
            app.save_layout('circle');
            app.rebuild_system();
        end

        % Menu selected function: forceMenu
        function forceMenuSelected(app, event)
            app.save_layout('force');
            app.rebuild_system();
        end

        % Menu selected function: layeredMenu
        function layeredMenuSelected(app, event)
            app.save_layout('layered');
            app.rebuild_system();
        end

        % Menu selected function: subspaceMenu
        function subspaceMenuSelected(app, event)
            app.save_layout('subspace');
            app.rebuild_system();
        end

        % Menu selected function: force3Menu
        function force3MenuSelected(app, event)
            app.save_layout('force3');
            app.rebuild_system();
        end

        % Menu selected function: subspace3Menu
        function subspace3MenuSelected(app, event)
            app.save_layout('subspace3');
            app.rebuild_system();
        end

        % Button pushed function: Add_node
        function Add_nodeButtonPushed(app, event)
            
            % check it whether we already have a node with this name
            if any(strcmp(app.node_name.Value,fieldnames(app.Ecoli.nodes)))
                % if the node exists, we are not doing anything
                fig = uifigure;
                uialert(fig,'We already have a node with this name!','Invalid Name','CloseFcn', @(~, ~) close(fig))
                waitfor(fig)
                delete(fig)
                return;
            end

            [app.Ecoli,added_node_name] = app.Ecoli.add_node(app.node_name.Value,app.node_type.Value);
            app.update_graph();

            % update regulator and protease list with the new node name
            app.addname2reglist(added_node_name);
            app.addname2protlist(added_node_name);
            % make sure that the actual regulated settings are valid
            app.regulated_nameValueChanged();

            % log
            app.log{end+1} = ['app.Ecoli = app.Ecoli.add_node(''' added_node_name ''',''' app.node_type.Value ''');'];

        end

        % Button pushed function: Set_node
        function Set_nodeButtonPushed(app, event)
            selected_object = app.select_node();
            if isempty(selected_object)
                return;
            end
            % clean up the model (deleted properties will not disturb in set()
            app.Ecoli = app.Ecoli.clean_up_cell();
            app.apphandle.set_object = set_object(app,app.Ecoli,selected_object);
        end

        % Button pushed function: Add_regulator
        function Add_regulatorButtonPushed(app, event)
            if ~isempty(app.regulated_name.Value)
                % type
                type = app.regulated_type.Value;

                % input ('Hill' parameter name)
                input = app.regulated_input.Value;

                % e.g. {'node', 'input', '|-', 'regulator1', 'input', '|-',
                % 'regulator2'}
                regrout = app.regulated_name.Value;
                if ischar(regrout)
                    % char => cell
                    regrout = {regrout};
                end
                name_reg = app.regulator_name.Value;
                % give a name to the regulator if it is not provided
                if nargin == 1 || isempty(name_reg)
                    names = {app.Ecoli.data.Mobj.Species.Name};
                    node_number = 1;
                    name_reg = 'R1';
                    % we are looking for a new name
                    while any(contains(names,name_reg))
                        node_number = node_number+1;
                        name_reg = ['R' int2str(node_number)];
                    end
                end
                regrout{end+1} = input;
                regrout{end+1} = name_reg;

                % get rid of the type
                regrout(strcmp(regrout,'<-')|strcmp(regrout,'|-')) = [];

                % add regulator
                [app.Ecoli,suffix] = app.Ecoli.add_regulator(type,regrout);
                regrout = strcat(',''',regrout,'''');
                % log
                app.log{end+1} = ['app.Ecoli = app.Ecoli.add_regulator(''' type ''''...
                     [regrout{:}] ');'];

                % update graph
                app.update_graph();

                % update regulated name list
                app.addname2reglist(suffix);

            end
        end

        % Button pushed function: Set_regulated
        function Set_regulatedButtonPushed(app, event)
            selected_object = app.select_regulated();
            if isempty(selected_object)
                return;
            end
            % clean up the model (deleted properties will not disturb in set()
            app.Ecoli = app.Ecoli.clean_up_cell();
            app.apphandle.set_object = set_object(app,app.Ecoli,selected_object);
        end

        % Button pushed function: Add_protease
        function Add_proteaseButtonPushed(app, event)
            
            if isempty(app.proteased_name.Value) || isempty(app.protease_type.Value)
                % we are not doing anything without a node
                fig = uifigure;
                uialert(fig,'Select a node first!','No node selected','CloseFcn', @(~, ~) close(fig))
                waitfor(fig)
                delete(fig)
                return;
            end
            % add protease
            [app.Ecoli,added_protease_name] =...
                app.Ecoli.add_protease(app.proteased_name.Value,app.protease_name.Value,app.protease_type.Value);
    
            % update graph
            app.update_graph();

            % update the regulator list
            app.addname2reglist(added_protease_name);
            % make sure that the actual regulated settings are valid
            app.regulated_nameValueChanged();

            % log
            app.log{end+1} = ['app.Ecoli = app.Ecoli.add_protease('''...
                app.proteased_name.Value ''',''' added_protease_name ''',''' app.protease_type.Value ''');'];

        end

        % Button pushed function: Set_protease
        function Set_proteaseButtonPushed(app, event)
            % select protease object
            selected_object = app.select_protease();
            if isempty(selected_object)
                return;
            end
            % clean up the model (deleted properties will not disturb in set()
            app.Ecoli = app.Ecoli.clean_up_cell();
            set_object(app,app.Ecoli,selected_object);
        end

        % Button pushed function: Delete_node
        function Delete_nodeButtonPushed(app, event)
            
            % check it whether the object exsists
            if ~isempty(app.node_name.Value) && ~any(strcmp(app.node_name.Value,fieldnames(app.Ecoli.nodes)))
                % if the node does not exists, we are not doing anything
                fig = uifigure;
                uialert(fig,['We do not have a node with name: "' app.node_name.Value '"!'],'Invalid Name','CloseFcn', @(~, ~) close(fig))
                waitfor(fig)
                delete(fig)
                return;
            end

            % select the node what will be deleted
            selected_object = app.select_node();
            if isempty(selected_object)
                return;
            end

            % === delete regulators ===
            % delete the regulators of the object recursively
            app.delete_regulator_chain(selected_object);

            % === delete regulation ===
            % delete every regulation when the actual node was the
            % regulator
            output_prefix = selected_object.Mobj.UserData.output_prefix;
            for i = 1:numel(output_prefix)
                reg_name = [output_prefix{i},selected_object.name];
                if isfield(app.Ecoli.data.regulator_routes,reg_name)
                    % regulation routes
                    regrouts = app.Ecoli.data.regulator_routes.(reg_name);
                    for j = 1:numel(regrouts)
                        % the actual regulation route
                        regrout = regrouts{j};
                        % the regulator object
                        regulator_object = eval(strrep(app.Ecoli.route2fieldnames(regrout),'cell.','app.Ecoli.'));
                        % modifying the geulation route
                        regrout(strcmp(regrout,'<-')|strcmp(regrout,'|-')) = [];
                        regrout = strcat(',''',regrout,'''');
                        % generating the proper regulator addition line
                        log_line = ['app.Ecoli = app.Ecoli.add_regulator(''' regulator_object.type ''''...
                            [regrout{:}] ');'];
                        % find this line in the log and delete it
                        app.log(strcmp(app.log,log_line)) = [];
                        % delete all relating settings
                        app.delete_set(regrouts{j});
                    end
                end
            end

            % === delete every node-protease interaction ===
            % go through on every protease
            for i = 1:numel(selected_object.protease)
                protease = selected_object.protease{i};
                type = app.Ecoli.proteases.(protease).type;
                % delete the protease-node interaction
                log_line = ['app.Ecoli = app.Ecoli.add_protease('''...
                    selected_object.name ''',''' protease ''',''' type ''');'];
                % find this line in the log and delete it
                app.log(strcmp(app.log,log_line)) = [];
                % delete all relating settings
                app.delete_set(selected_object.name);
            end

            % === delete the node ===
            % generate the corresponding log line to delete the node
            log_line = ['app.Ecoli = app.Ecoli.add_node(''' selected_object.name ''',''' selected_object.type ''');'];

            % find this line in the log and delete it
            app.log(strcmp(app.log,log_line)) = [];
            % delete all relating settings
            app.delete_set(selected_object.name);

            % rebuild the system from the log file
            app.rebuild_system();

        end

        % Button pushed function: Delete_protease
        function Delete_proteaseButtonPushed(app, event)

            % check it whether the protease object exsists
            if ~isempty(app.protease_name.Value) && ~any(strcmp(app.protease_name.Value,fieldnames(app.Ecoli.proteases)))
                % if the node does not exists, we are not doing anything
                fig = uifigure;
                uialert(fig,['We do not have a protease with name: "' app.protease_name.Value '"!'],'Invalid Name','CloseFcn', @(~, ~) close(fig))
                waitfor(fig)
                delete(fig)
                return;
            end

            % select protease object
            selected_object = app.select_protease();
            if isempty(selected_object)
                return;
            end

            % check it whether the protease is connected to the given node
            if ~contains(selected_object.node_list,app.proteased_name.Value)
                % if the interaction does not exists, we are not doing anything
                fig = uifigure;
                uialert(fig,['We do not have a protease ("' selected_object.name '") - node ("' app.proteased_name.Value '") interaction!'],'Invalid Name','CloseFcn', @(~, ~) close(fig))
                waitfor(fig)
                delete(fig)
                return;
            end

            % generate the corresponding log line
            if isempty(app.proteased_name.Value)
                % we are deleteing the protease and the whole regulation
                % chain with it, otherwise we are deleting only the
                % interaction
                % delete the regulators of the object recursively
                app.delete_regulator_chain(selected_object);
            end
            log_line = ['app.Ecoli = app.Ecoli.add_protease('''...
                app.proteased_name.Value ''',''' selected_object.name ''',''' selected_object.type ''');'];

            % find this line in the log and delete it
            app.log(strcmp(app.log,log_line)) = [];
            % delete all relating settings
            app.delete_set(selected_object.name);

            % rebuild the system from the log file
            app.rebuild_system();

        end

        % Button pushed function: Delete_regulator
        function Delete_regulatorButtonPushed(app, event)
            % select a regulator based on the regulator list
            regname = app.regulated_name.Value;

            % if it is a regulator or a protease we should delete them
            % accordingly
            if app.Ecoli.isNode(regname)
                app.node_name.Value = char(regname);
                Delete_nodeButtonPushed(app);
                app.node_name.Value = '';
                return;
            end
            if app.Ecoli.isProtease(regname)
                app.protease_name.Value = char(regname);
                % find the protease
                protease = app.select_protease();
                % delete the regulators of the object recursively
                app.delete_regulator_chain(protease);
                % delete every interactions of the protease
                app.delete_protease_interactions(protease);
                app.protease_name.Value = '';
                return;
            end

            selected_object = eval(strrep(app.Ecoli.route2fieldnames(regname),'cell.','app.Ecoli.'));

            % regulation route
            regrout = selected_object.suffix;
            regrout(strcmp(regrout,'<-')|strcmp(regrout,'|-')) = [];
            regrout = strcat(',''',regrout,'''');

            % generate the corresponding log line
            log_line = ['app.Ecoli = app.Ecoli.add_regulator(''' selected_object.type ''''...
                [regrout{:}] ');'];

            % find ithis line in the log and delete it
            app.log(strcmp(app.log,log_line)) = [];
            % delete all relating settings
            app.delete_set(selected_object.suffix);

            % delete the regulators of the object recursively
            app.delete_regulator_chain(selected_object);
          
            % rebuild the system from the log file
            app.rebuild_system();

        end

        % Menu selected function: OpeninSimBiologyMenu
        function OpeninSimBiologyMenuSelected(app, event)
            simBiologyModelBuilder(app.Ecoli.get_model());
%             simBiologyModelBuilder(app.Ecoli.data.Mobj);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            saved.Ecoli = app.Ecoli;
            % copy from the handle class
            saved.Ecoli_SharedData = copy(app.Ecoli.data);
            % app properties
            saved.log = app.log;
            saved.proteased_name.Items = app.proteased_name.Items;
            saved.regulated_name.Items = app.regulated_name.Items;
            saved.regulated_name.ItemsData = app.regulated_name.ItemsData; %#ok<STRNU>
 
            uisave('saved');
            % set the current active figure
            figure(app.UIFigure)
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
           % To reinitialize the constant property, clear all instances 
            % of the class and then clear the class
            % delete every "bacteria" class variable
            % app.clearClasses('bacteria')

            uiopen('matlab');
            if exist('saved','var')

                app.Ecoli = saved.Ecoli;
%                 % set the properties for the handle class
%                 app.Ecoli.data.set_properties(saved.Ecoli_SharedData);

                % app properties
                app.log = saved.log;
                % if it contained %% Run simulation part, delete it
                app.log_run_delete();
%                 app.proteased_name.Items = saved.proteased_name.Items;
%                 app.regulated_name.Items = saved.regulated_name.Items;
%                 app.regulated_name.ItemsData = saved.regulated_name.ItemsData;
% 

                % rebuild system rom log file
                app.rebuild_system();
            end
            % set the current active figure
            figure(app.UIFigure)
        end

        % Menu selected function: RunMenu
        function RunMenuSelected(app, event)
            % without a species we will not run simulations
            if isempty({app.Ecoli.data.Mobj.Species.Name})
                return;
            end
            % open other app
            app.apphandle.run_simulation = run_simulations(app,app.Ecoli,app.solver);
        end

        % Menu selected function: SettingsMenu
        function SettingsMenuSelected(app, event)
            % make simulation settings
            app.apphandle.set_simulation = set_simulation(app,app.Ecoli,app.solver);
        end

        % Menu selected function: SimulationMenu
        function SimulationMenuSelected(app, event)

        end

        % Menu selected function: ExportCodeMenu
        function ExportCodeMenuSelected(app, event)
            app.apphandle.export_code = export_code(app,app.log,app.solver,app.Ecoli.data.Accelerate);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            % % save the app figure 
            % exportapp(app.UIFigure, 'GRN_designer_figure.pdf');
            % exportgraphics(app.UIAxes, 'GRN_designer_axes.pdf');
            
            % close every sub application before
            if isfield(app.apphandle,'export_code')
                delete(app.apphandle.export_code)
            end
            if isfield(app.apphandle,'set_object')
                delete(app.apphandle.set_object)
            end
            if isfield(app.apphandle,'set_simulation')
                delete(app.apphandle.set_simulation)
            end
            if isfield(app.apphandle,'model_selection')
                delete(app.apphandle.model_selection)
            end
            if isfield(app.apphandle,'run_simulation')
                if isprop(app.apphandle.run_simulation,'UIFigure')
                    close(app.apphandle.run_simulation.UIFigure)
                end
                delete(app.apphandle.run_simulation)
            end
            if isfield(app.apphandle,'export_SBML')
                delete(app.apphandle.export_SBML)
            end
            if isfield(app.apphandle,'add_rule_param')
                delete(app.apphandle.add_rule_param)
            end
            if isfield(app.apphandle,'set_object')
                delete(app.apphandle.set_object)
            end
            if isfield(app.apphandle,'graph_settings')
                delete(app.apphandle.graph_settings)
            end
            if isfield(app.apphandle,'spatial_sim')
                delete(app.apphandle.spatial_sim)
            end
            delete(app)
        end

        % Value changed function: regulator_list
        function regulator_listValueChanged(app, event)
            value = app.regulator_list.Value;
            app.regulator_name.Value = value;
        end

        % Value changed function: regulator_name
        function regulator_nameValueChanged(app, event)
            value = app.regulator_name.Value;
            if any(strcmp(app.regulator_list.Items,value))
                app.regulator_list.Value = value;
            end
        end

        % Value changed function: regulated_name
        function regulated_nameValueChanged(app, event)
            
            reg = app.regulated_name.Value;

            if ~isempty(reg)
                % get the regulatedobject
                reg = strrep(app.Ecoli.route2fieldnames(reg),'cell','app.Ecoli');
                reg = eval(reg);

                % set the input
                app.regulated_input.Items = reg.Mobj.UserData.input;
            end
        end

        % Value changed function: regulated_type
        function regulated_typeValueChanged(app, event)

        end

        % Menu selected function: ModelMenu
        function ModelMenuSelected(app, event)

        end

        % Menu selected function: SelectthemodelMenu
        function SelectthemodelMenuSelected(app, event)
            app.apphandle(1).model_selection = model_selection(app,app.selected_model);
            if isvalid(app.apphandle.model_selection)
                waitfor(app.apphandle.model_selection)
            end
            % if the application was closed in the meantime
            if ~isvalid(app)
                return;
            end
            % if we already had a model
            if ~isempty(app.Ecoli)
                app.model_change(app.selected_model);
            end
        end

        % Menu selected function: OpeninCOPASIMenu
        function OpeninCOPASIMenuSelected(app, event)
%             % change the model that it could be compatible with COPASI
%             % get the model
%             Mobj = app.Ecoli.get_model();
%             % make the reactions irreversible (for stochastic solvers)
%             Mobj = convert2irrev(Mobj);
%             % change the reactions to take care of "modifiers"
%             Mobj = correct_modifiers(Mobj);
%             % export SBML file into the temporary directory of the system
%             sbmlexport(Mobj, [tempdir 'model.sbml']);
%             
%             % open it with COPASI
%             system(['/opt/COPASI/4.38.268/bin/CopasiUI ' tempdir 'model.sbml']);

            % get the model
            Mobj = app.Ecoli.get_model();
            % we want to run COPASI
            runCOPASI = true;
            % open the application
            app.apphandle.export_SBML = export_SBML(Mobj,runCOPASI);

        end

        % Menu selected function: ExportSBMLMenu
        function ExportSBMLMenuSelected(app, event)
            % get the model
            Mobj = app.Ecoli.get_model();
            % we do not want to run COPASI
            runCOPASI = false;
            % open the application
            app.apphandle.export_SBML = export_SBML(Mobj,runCOPASI);
        end

        % Button pushed function: AddrulesparametersButton
        function AddrulesparametersButtonPushed(app, event)
            % get the model
            Mobj = app.Ecoli.get_model();
            % open the app
            app.apphandle.add_rule_param = add_rule_param(app,Mobj);
        end

        % Menu selected function: GraphsettingsMenu
        function GraphsettingsMenuSelected(app, event)
            app.apphandle.graph_settings = graph_settings(app,app.Ecoli);
        end

        % Menu selected function: LoadexternalmodelMenu
        function LoadexternalmodelMenuSelected(app, event)
            % we can load an sbml or a simbiology external model
            % which will be added to our model before simulation
            [file,path] = uigetfile({'*.sbml';'*.sbproj'});
            if file~=0
                if contains(file,'.sbml')
                    % import sbml model
                    app.Ecoli.data.Mobj_extern = sbmlimport([path,file]);
                    % log
                    app.log{end+1} = ['app.Ecoli.data.Mobj_extern = sbmlimport(''' [path,file] ''');'];
                elseif contains(file,'.sbproj')
                    % imopiort simbiology model
                    app.Ecoli.data.Mobj_extern = sbioloadproject([path,file]);
                    % log
                    app.log{end+1} = ['app.Ecoli.data.Mobj_extern = sbioloadproject(''' [path,file] ''');'];
                end
            end
            
            % show main figure on the top again
            figure(app.UIFigure);
        end

        % Menu selected function: SpatialsimulationMenu
        function SpatialsimulationMenuSelected(app, event)
            % open other app
            app.apphandle.spatial_sim = spatial_simulations(app.Ecoli);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InputOutputMenu = uimenu(app.UIFigure);
            app.InputOutputMenu.Text = 'Input/Output';
            app.SaveMenu = uimenu(app.InputOutputMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.LoadMenu = uimenu(app.InputOutputMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.ExportCodeMenu = uimenu(app.InputOutputMenu);
            app.ExportCodeMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportCodeMenuSelected, true);
            app.ExportCodeMenu.Text = 'Export Code';
            app.LoadexternalmodelMenu = uimenu(app.InputOutputMenu);
            app.LoadexternalmodelMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadexternalmodelMenuSelected, true);
            app.LoadexternalmodelMenu.Text = 'Load external model';
            app.SimulationMenu = uimenu(app.UIFigure);
            app.SimulationMenu.MenuSelectedFcn = createCallbackFcn(app, @SimulationMenuSelected, true);
            app.SimulationMenu.Text = 'Simulation';
            app.SettingsMenu = uimenu(app.SimulationMenu);
            app.SettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SettingsMenuSelected, true);
            app.SettingsMenu.Text = 'Settings';
            app.RunMenu = uimenu(app.SimulationMenu);
            app.RunMenu.MenuSelectedFcn = createCallbackFcn(app, @RunMenuSelected, true);
            app.RunMenu.Text = 'Run';
            app.OpeninSimBiologyMenu = uimenu(app.SimulationMenu);
            app.OpeninSimBiologyMenu.MenuSelectedFcn = createCallbackFcn(app, @OpeninSimBiologyMenuSelected, true);
            app.OpeninSimBiologyMenu.Text = 'Open in SimBiology';
            app.OpeninCOPASIMenu = uimenu(app.SimulationMenu);
            app.OpeninCOPASIMenu.MenuSelectedFcn = createCallbackFcn(app, @OpeninCOPASIMenuSelected, true);
            app.OpeninCOPASIMenu.Text = 'Open in COPASI';
            app.SpatialsimulationMenu = uimenu(app.SimulationMenu);
            app.SpatialsimulationMenu.MenuSelectedFcn = createCallbackFcn(app, @SpatialsimulationMenuSelected, true);
            app.SpatialsimulationMenu.Text = 'Spatial simulation';
            app.ModelMenu = uimenu(app.UIFigure);
            app.ModelMenu.MenuSelectedFcn = createCallbackFcn(app, @ModelMenuSelected, true);
            app.ModelMenu.Text = 'Model';
            app.SelectthemodelMenu = uimenu(app.ModelMenu);
            app.SelectthemodelMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectthemodelMenuSelected, true);
            app.SelectthemodelMenu.Text = 'Select the model';
            app.ExportSBMLMenu = uimenu(app.ModelMenu);
            app.ExportSBMLMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportSBMLMenuSelected, true);
            app.ExportSBMLMenu.Text = 'Export SBML';
            app.SettingsMenu_2 = uimenu(app.UIFigure);
            app.SettingsMenu_2.Text = 'Settings';
            app.GraphsettingsMenu = uimenu(app.SettingsMenu_2);
            app.GraphsettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @GraphsettingsMenuSelected, true);
            app.GraphsettingsMenu.Text = 'Graph settings';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph of the circuit')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Tag = 'graph_axes';
            app.NodePropertiesPanel = uipanel(app.UIFigure);
            app.NodePropertiesPanel.Title = 'Node Properties';
            app.NodeNameLabel = uilabel(app.NodePropertiesPanel);
            app.NodeNameLabel.Text = 'Node Name';
            app.node_name = uieditfield(app.NodePropertiesPanel, 'text');
            app.Add_node = uibutton(app.NodePropertiesPanel, 'push');
            app.Add_node.ButtonPushedFcn = createCallbackFcn(app, @Add_nodeButtonPushed, true);
            app.Add_node.Text = 'Add';
            app.Set_node = uibutton(app.NodePropertiesPanel, 'push');
            app.Set_node.ButtonPushedFcn = createCallbackFcn(app, @Set_nodeButtonPushed, true);
            app.Set_node.Text = 'Set';
            app.Delete_node = uibutton(app.NodePropertiesPanel, 'push');
            app.Delete_node.ButtonPushedFcn = createCallbackFcn(app, @Delete_nodeButtonPushed, true);
            app.Delete_node.Text = 'Delete Node';
            app.TypeDropDownLabel_2 = uilabel(app.NodePropertiesPanel);
            app.TypeDropDownLabel_2.Text = 'Type';
            app.node_type = uidropdown(app.NodePropertiesPanel);
            app.node_type.Items = {};
            app.node_type.Value = {};
            app.RegulatorPropertiesPanel = uipanel(app.UIFigure);
            app.RegulatorPropertiesPanel.Title = 'Regulator Properties';
            app.RegulatorLabel = uilabel(app.RegulatorPropertiesPanel);
            app.RegulatorLabel.Text = 'Regulator Name';
            app.regulator_name = uieditfield(app.RegulatorPropertiesPanel, 'text');
            app.regulator_name.ValueChangedFcn = createCallbackFcn(app, @regulator_nameValueChanged, true);
            app.TypeDropDownLabel = uilabel(app.RegulatorPropertiesPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.regulated_type = uidropdown(app.RegulatorPropertiesPanel);
            app.regulated_type.Items = {};
            app.regulated_type.ValueChangedFcn = createCallbackFcn(app, @regulated_typeValueChanged, true);
            app.regulated_type.Value = {};
            app.RegulatedLabel = uilabel(app.RegulatorPropertiesPanel);
            app.RegulatedLabel.Text = 'Regulated';
            app.regulated_name = uidropdown(app.RegulatorPropertiesPanel);
            app.regulated_name.Items = {};
            app.regulated_name.ValueChangedFcn = createCallbackFcn(app, @regulated_nameValueChanged, true);
            app.regulated_name.Value = {};
            app.Add_regulator = uibutton(app.RegulatorPropertiesPanel, 'push');
            app.Add_regulator.ButtonPushedFcn = createCallbackFcn(app, @Add_regulatorButtonPushed, true);
            app.Add_regulator.Text = 'Add';
            app.Set_regulated = uibutton(app.RegulatorPropertiesPanel, 'push');
            app.Set_regulated.ButtonPushedFcn = createCallbackFcn(app, @Set_regulatedButtonPushed, true);
            app.Set_regulated.Text = 'Set';
            app.Delete_regulator = uibutton(app.RegulatorPropertiesPanel, 'push');
            app.Delete_regulator.ButtonPushedFcn = createCallbackFcn(app, @Delete_regulatorButtonPushed, true);
            app.Delete_regulator.Text = 'Delete Regulated';
            app.regulator_list = uidropdown(app.RegulatorPropertiesPanel);
            app.regulator_list.Items = {' '};
            app.regulator_list.ValueChangedFcn = createCallbackFcn(app, @regulator_listValueChanged, true);
            app.regulator_list.Value = ' ';
            app.RegulatedLabel_3 = uilabel(app.RegulatorPropertiesPanel);
            app.RegulatedLabel_3.Text = {'Input'; 'function'};
            app.regulated_input = uidropdown(app.RegulatorPropertiesPanel);
            app.regulated_input.Items = {''};
            app.regulated_input.Value = '';
            app.ProteasePropertiesPanel = uipanel(app.UIFigure);
            app.ProteasePropertiesPanel.Title = 'Protease Properties';
            app.ProteaseNameLabel = uilabel(app.ProteasePropertiesPanel);
            app.ProteaseNameLabel.Text = 'Protease Name';
            app.protease_name = uieditfield(app.ProteasePropertiesPanel, 'text');
            app.RegulatedLabel_2 = uilabel(app.ProteasePropertiesPanel);
            app.RegulatedLabel_2.Text = 'Regulated Node';
            app.proteased_name = uidropdown(app.ProteasePropertiesPanel);
            app.proteased_name.Items = {''};
            app.proteased_name.Value = '';
            app.Add_protease = uibutton(app.ProteasePropertiesPanel, 'push');
            app.Add_protease.ButtonPushedFcn = createCallbackFcn(app, @Add_proteaseButtonPushed, true);
            app.Add_protease.Text = 'Add';
            app.Set_protease = uibutton(app.ProteasePropertiesPanel, 'push');
            app.Set_protease.ButtonPushedFcn = createCallbackFcn(app, @Set_proteaseButtonPushed, true);
            app.Set_protease.Text = 'Set';
            app.Delete_protease = uibutton(app.ProteasePropertiesPanel, 'push');
            app.Delete_protease.ButtonPushedFcn = createCallbackFcn(app, @Delete_proteaseButtonPushed, true);
            app.Delete_protease.Text = 'Delete Interaction';
            app.TypeDropDownLabel_3 = uilabel(app.ProteasePropertiesPanel);
            app.TypeDropDownLabel_3.Text = 'Type';
            app.protease_type = uidropdown(app.ProteasePropertiesPanel);
            app.protease_type.Items = {};
            app.protease_type.Value = {};
            app.AddrulesparametersButton = uibutton(app.UIFigure, 'push');
            app.AddrulesparametersButton.ButtonPushedFcn = createCallbackFcn(app, @AddrulesparametersButtonPushed, true);
            app.AddrulesparametersButton.Text = 'Add rules, parameters';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.LayoutMenu = uimenu(app.ContextMenu);
            app.LayoutMenu.MenuSelectedFcn = createCallbackFcn(app, @LayoutMenuSelected, true);
            app.LayoutMenu.Text = 'Layout';
            app.circleMenu = uimenu(app.LayoutMenu);
            app.circleMenu.MenuSelectedFcn = createCallbackFcn(app, @circleMenuSelected, true);
            app.circleMenu.Text = 'circle';
            app.forceMenu = uimenu(app.LayoutMenu);
            app.forceMenu.MenuSelectedFcn = createCallbackFcn(app, @forceMenuSelected, true);
            app.forceMenu.Text = 'force';
            app.layeredMenu = uimenu(app.LayoutMenu);
            app.layeredMenu.MenuSelectedFcn = createCallbackFcn(app, @layeredMenuSelected, true);
            app.layeredMenu.Text = 'layered';
            app.subspaceMenu = uimenu(app.LayoutMenu);
            app.subspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @subspaceMenuSelected, true);
            app.subspaceMenu.Text = 'subspace';
            app.force3Menu = uimenu(app.LayoutMenu);
            app.force3Menu.MenuSelectedFcn = createCallbackFcn(app, @force3MenuSelected, true);
            app.force3Menu.Text = 'force3';
            app.subspace3Menu = uimenu(app.LayoutMenu);
            app.subspace3Menu.MenuSelectedFcn = createCallbackFcn(app, @subspace3MenuSelected, true);
            app.subspace3Menu.Text = 'subspace3';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GRN_modeler

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end