classdef add_rule_param < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AddParameterPanel              matlab.ui.container.Panel
        CopyUnitfromparameterDropDown  matlab.ui.control.DropDown
        CopyUnitfromparameterDropDownLabel  matlab.ui.control.Label
        AddParameterButton             matlab.ui.control.Button
        UnitEditField                  matlab.ui.control.EditField
        UnitEditFieldLabel             matlab.ui.control.Label
        ValueEditField                 matlab.ui.control.NumericEditField
        ValueEditFieldLabel            matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        AddrulePanel                   matlab.ui.container.Panel
        RuleNameEditField              matlab.ui.control.EditField
        RuleNameEditFieldLabel         matlab.ui.control.Label
        AddRuleButton                  matlab.ui.control.Button
        RegulatorDropDownLabel         matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        RuleTypeDropDown               matlab.ui.control.DropDown
        RuleTypeDropDownLabel          matlab.ui.control.Label
        ParameterDropDown              matlab.ui.control.DropDown
        ParameterDropDownLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp; % main application
        Mobj; % simbiology model
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Mobj)
            
            app.MainApp = mainapp;
            app.Mobj = Mobj;
            
            params = [{app.Mobj.Parameters.Name},{app.Mobj.Species.Name}];
            
            % set up parameter list
            app.ParameterDropDown.Items = params.';
            % set up parameter list
            app.CopyUnitfromparameterDropDown.Items = params.';
            
        end

        % Button pushed function: AddRuleButton
        function AddRuleButtonPushed(app, event)
            
            if ~isempty(sbioselect(app.Mobj.Rules,'Name',app.RuleNameEditField.Value))
                warndlg('We can not add the rule, a rule with the same nam already exists.','Warning');
            else
                % create a log file
                log{1,1} = ['set(sbioselect(app.Ecoli.data.Mobj,''Name'','''...
                    app.ParameterDropDown.Value '''),''Constant'',false);'];
                log{2,1} = ['addrule(app.Ecoli.data.Mobj,'''...
                    app.ParameterDropDown.Value ' = ' app.EditField.Value ''','''...
                    app.RuleTypeDropDown.Value ''',''Name'',''' app.RuleNameEditField.Value ''');'];

                % add the log file to the main app log file
                app.MainApp.update_eval_log(log);
            end
        end

        % Button pushed function: AddParameterButton
        function AddParameterButtonPushed(app, event)
            
            % if the parameter exists
            if ~isempty(sbioselect(app.Mobj.Parameters,'Name',app.NameEditField.Value))

                % Create confirmation dialog box
                fig = uifigure;
                msg = "The parameter already exsists. Would you like to change it?";
                title = "Confirm override";
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Yes","No"], ...
                    "DefaultOption",1,"CancelOption",2);

                switch selection
                    % change the existing parameter
                    case "Yes"
                        % add parame"Zter to the model
                        set(sbioselect(app.Mobj.Parameters,'Name',app.NameEditField.Value),...
                            'Value',app.ValueEditField.Value,'Units',app.UnitEditField.Value);
                        % create a log file
                        log{1,1} = ['set(sbioselect(app.Ecoli.data.Mobj.Parameters,''Name'',''' app.NameEditField.Value '''),'...
                            '''Value'',' num2str(app.ValueEditField.Value,'%e') ',''Units'',''' app.UnitEditField.Value ''');'];
                        % add the log file to the main app log file
                        app.MainApp.update_eval_log(log);
                end
                close(fig)

            else

                % add parameter to the model
                addparameter(app.Mobj,app.NameEditField.Value,'Value', app.ValueEditField.Value,...
                    'Units',app.UnitEditField.Value,'Notes','Individual');
                % create a log file
                log{1,1} = ['addparameter(app.Ecoli.data.Mobj,'''...
                    app.NameEditField.Value ''',''Value'',' num2str(app.ValueEditField.Value,'%e')...
                    ',''Units'',''' app.UnitEditField.Value...
                    ''',''Notes'',''Individual'');'];
                % add the log file to the main app log file
                app.MainApp.update_eval_log(log);

                % set up parameter list
                app.ParameterDropDown.Items = {app.Mobj.Parameters.Name}.';
                % set up parameter list
                app.CopyUnitfromparameterDropDown.Items = {app.Mobj.Parameters.Name}.';
            end
        end

        % Value changed function: CopyUnitfromparameterDropDown
        function CopyUnitfromparameterDropDownValueChanged(app, event)
            value = app.CopyUnitfromparameterDropDown.Value;
            app.UnitEditField.Value = get(sbioselect(app.Mobj,'Name',value),'Units');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddrulePanel = uipanel(app.UIFigure);
            app.AddrulePanel.Title = 'Add rule';
            app.ParameterDropDownLabel = uilabel(app.AddrulePanel);
            app.ParameterDropDownLabel.Text = 'Parameter';
            app.ParameterDropDown = uidropdown(app.AddrulePanel);
            app.ParameterDropDown.Items = {''};
            app.ParameterDropDown.Value = '';
            app.RuleTypeDropDownLabel = uilabel(app.AddrulePanel);
            app.RuleTypeDropDownLabel.Text = 'RuleType';
            app.RuleTypeDropDown = uidropdown(app.AddrulePanel);
            app.RuleTypeDropDown.Items = {'repeatedAssignment', 'initialAssignment', 'rate', 'algebraic'};
            app.RuleTypeDropDown.Value = 'repeatedAssignment';
            app.EditField = uieditfield(app.AddrulePanel, 'text');
            app.EditField.Value = 'A*(1+sin(2*pi/T*time))/2';
            app.RegulatorDropDownLabel = uilabel(app.AddrulePanel);
            app.RegulatorDropDownLabel.Text = 'Rule';
            app.AddRuleButton = uibutton(app.AddrulePanel, 'push');
            app.AddRuleButton.ButtonPushedFcn = createCallbackFcn(app, @AddRuleButtonPushed, true);
            app.AddRuleButton.Text = 'Add Rule';
            app.RuleNameEditFieldLabel = uilabel(app.AddrulePanel);
            app.RuleNameEditFieldLabel.Text = 'Rule Name';
            app.RuleNameEditField = uieditfield(app.AddrulePanel, 'text');
            app.RuleNameEditField.Value = 'SinWave';
            app.AddParameterPanel = uipanel(app.UIFigure);
            app.AddParameterPanel.Title = 'Add Parameter';
            app.NameEditFieldLabel = uilabel(app.AddParameterPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.AddParameterPanel, 'text');
            app.ValueEditFieldLabel = uilabel(app.AddParameterPanel);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.AddParameterPanel, 'numeric');
            app.UnitEditFieldLabel = uilabel(app.AddParameterPanel);
            app.UnitEditFieldLabel.Text = 'Unit';
            app.UnitEditField = uieditfield(app.AddParameterPanel, 'text');
            app.AddParameterButton = uibutton(app.AddParameterPanel, 'push');
            app.AddParameterButton.ButtonPushedFcn = createCallbackFcn(app, @AddParameterButtonPushed, true);
            app.AddParameterButton.Text = 'Add Parameter';
            app.CopyUnitfromparameterDropDownLabel = uilabel(app.AddParameterPanel);
            app.CopyUnitfromparameterDropDownLabel.Text = 'Copy Unit from parameter';
            app.CopyUnitfromparameterDropDown = uidropdown(app.AddParameterPanel);
            app.CopyUnitfromparameterDropDown.Items = {''};
            app.CopyUnitfromparameterDropDown.ValueChangedFcn = createCallbackFcn(app, @CopyUnitfromparameterDropDownValueChanged, true);
            app.CopyUnitfromparameterDropDown.Value = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = add_rule_param(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end