classdef export_SBML < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        OKButton             matlab.ui.control.Button
        SBMLpropertiesPanel  matlab.ui.container.Panel
        changereactionswithmodifiersCheckBox  matlab.ui.control.CheckBox
        makereactionsirreversibleCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        Mobj % simbiology model
        runCOPASI % whether we want to run copasi or not
        location (1,:) char;% path for the folder of the app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mobj, runCOPASI)

            % move the app in the middle of the screen
            movegui(app.UIFigure,'center');
           
            % path to the folder of the app
            app.location = strrep(mfilename('fullpath'),'export_SBML','');
            
            app.Mobj = mobj;
            app.runCOPASI = runCOPASI;

        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % change the model that it could be compatible with COPASI
            % make the reactions irreversible (for stochastic solvers)
            if app.makereactionsirreversibleCheckBox.Value == true
                app.Mobj = convert2irrev(app.Mobj);
            end
            % change the reactions to take care of "modifiers"
            if app.changereactionswithmodifiersCheckBox.Value == true
                app.Mobj = correct_modifiers(app.Mobj);
            end
            if app.runCOPASI == true
                try
                    % export SBML file into the temporary directory of the system
                    sbmlexport(app.Mobj, [tempdir 'model.sbml']);
                    if ismac
                        [status,~] = system(['open -a CopasiUI "' tempdir 'model.sbml"']);
                    elseif ispc
                        try % in win it is with lower case, copasiUI not CopasiUI
                            [status,~] = system(['copasiUI "' tempdir 'model.sbml"']);
                            assert(status == 0)
                        catch
                            [status,~] = system(['powershell -command copasiUI "' tempdir 'model.sbml"']);
                        end
                    else
                        [status,~] = system(['CopasiUI "' tempdir 'model.sbml"']);
                    end
                    assert(status == 0)
                    % Delete the dialog box
                    delete(app)
                catch
                    try
                        % if CopasiUI is not on the path lets see if we have
                        % saved its location already
                        try
                            load([app.location,'COPASI_path'],'CopasiUI_path');
                        catch
                            if ispc
                                [status,CopasiUI_path] = system('where copasiUI');
                            else
                                [status,CopasiUI_path] = system('which CopasiUI');
                            end
                            assert(status == 0)
                            save([app.location,'COPASI_path'],'CopasiUI_path');
                        end
                        if ismac
                            [status,cmdout] = system(['open -a ' CopasiUI_path ' "' tempdir 'model.sbml"']);
                        elseif ispc
                            try
                                [status,cmdout] = system(['"' CopasiUI_path '" "' tempdir 'model.sbml"']);
                                assert(status == 0)
                            catch
                                [status,cmdout] = system(['powershell -command "' CopasiUI_path '" "' tempdir 'model.sbml"']);
                            end
                        else
                            [status,cmdout] = system([CopasiUI_path ' "' tempdir 'model.sbml"']);
                        end
                        assert(status == 0)
                        %disp(cmdout)
                        % Delete the dialog box
                        delete(app)
                    catch
                        [file,loc] = uigetfile('*.*','Try to locate CopasiUI','CopasiUI');
                        if (ischar(file)||file~=0) && (ischar(loc)||loc~=0)
                            CopasiUI_path = [loc file];
                            save([app.location,'COPASI_path'],'CopasiUI_path');
                        end
                        % lets try it again
                        app.OKButtonPushed();
                        return;
                    end
                end
            else % export sbml
                % select the output
                [file,loc] = uiputfile('.sbml');
                if isempty(file) || ~ischar(file)
                    return
                end
                % export SBML file into the temporary directory of the system
                sbmlexport(app.Mobj, [loc file]);
                % Delete the dialog box
                delete(app)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SBMLpropertiesPanel = uipanel(app.UIFigure);
            app.SBMLpropertiesPanel.Title = 'SBML properties';
            app.makereactionsirreversibleCheckBox = uicheckbox(app.SBMLpropertiesPanel);
            app.makereactionsirreversibleCheckBox.Text = 'make reactions irreversible';
            app.makereactionsirreversibleCheckBox.Value = true;
            app.changereactionswithmodifiersCheckBox = uicheckbox(app.SBMLpropertiesPanel);
            app.changereactionswithmodifiersCheckBox.Text = 'change reactions with modifiers';
            app.changereactionswithmodifiersCheckBox.Value = true;
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = export_SBML(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end