classdef export_code < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        AddrunandplotButton  matlab.ui.control.Button
        SaveButton           matlab.ui.control.Button
        RebuildmodelButton   matlab.ui.control.Button
        Code_text            matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        MainApp % main application
        solver  % the selected solver
        Accelerate
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, log, solver, accelerate)
            
            % movethe app in the middle of the screen
            % movegui(app.UIFigure,'center');
            
            app.MainApp = mainapp;
            app.solver = solver;
            app.Accelerate = accelerate;

            % build at the end
            % log{end+1} = 'app.Ecoli.build();';
            app.Code_text.Value = log;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            % get rid of 'app.'
            text = strrep(app.Code_text.Value,'app.','');

            % one extra line at the end to get the model:
            text{end+1} = 'Mobj = Ecoli.get_model();';

            % select the output
            [file,location] = uiputfile('.m');
            if file==0
                % no output determined
                return;
            end
            % open file
            fileID = fopen([location,file],'w');
            % print the text
            fprintf(fileID,'%s\n',text{:});
            fclose(fileID);

            % % write the code into a text file
            % writecell(app.Code_text.Value,[app.FilenameEditField.Value,'.txt'],QuoteStrings="none")
            % % rename it to a '.m' file
            % movefile([app.FilenameEditField.Value,'.txt'],[app.FilenameEditField.Value,'.m'])

            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: RebuildmodelButton
        function RebuildmodelButtonPushed(app, event)
            % set the log in the main app
            app.MainApp.set_log(app.Code_text.Value);

            % rebuild the system based on the log file
            app.MainApp.rebuild_system();

            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: AddrunandplotButton
        function AddrunandplotButtonPushed(app, event)
            app.Code_text.Value{end+1} = '%% Run simulation';
            app.Code_text.Value{end+1} = 'app.Mobj = app.Ecoli.get_model();';
            switch app.solver
                case {'ode15s','ode45','sundials'} % MATAB sovlers

                    % set the solver
                    app.Code_text.Value{end+1} = 'set(getconfigset(app.Mobj),''SolverType'',app.solver);';

                    % accelerate
                    if app.Accelerate == true
                        app.Code_text.Value{end+1} = 'sbioaccelerate(app.Mobj);';
                    end

                otherwise % COPASI

                    % get the configset
                    app.Code_text.Value{end+1} = 'configset = getconfigset(app.Mobj);';
                    % convert the model to get only irreversible reactions
                    % it is necessary for stochastic solvers
                    app.Code_text.Value{end+1} = 'app.Mobj = convert2irrev(app.Mobj);';
                    % make extra alterations on the model to be compatible
                    % with COPASI
                    app.Code_text.Value{end+1} = 'app.Mobj = correct_modifiers(app.Mobj);';
                    % set the original configset in the model
                    app.Code_text.Value{end+1} = 'app.Ecoli.set_configset(app.Mobj,configset)';

            end

            % run simulation
            app.Code_text.Value{end+1} = '[t,c,names] = run_simulation(app.Mobj,app.solver);';


            % plot
            app.Code_text.Value{end+1} = 'figure';
            app.Code_text.Value{end+1} = 'hold on';
            app.Code_text.Value{end+1} = 'for i = 1:numel(app.Ecoli.data.StatesToLog)';
            app.Code_text.Value{end+1} = '    plot(t,c(:,strcmp(names,app.Ecoli.data.StatesToLog{i})))';
            app.Code_text.Value{end+1} = 'end';
            app.Code_text.Value{end+1} = 'xlabel(''time'')';
            app.Code_text.Value{end+1} = 'ylabel(''concentration'')';
            app.Code_text.Value{end+1} = 'legend(app.Ecoli.data.StatesToLog,''Interpreter'',''none'')';

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            % % save the app figure 
            % exportapp(app.UIFigure, 'export_code_figure.pdf');

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Code_text = uitextarea(app.UIFigure);
            app.RebuildmodelButton = uibutton(app.UIFigure, 'push');
            app.RebuildmodelButton.ButtonPushedFcn = createCallbackFcn(app, @RebuildmodelButtonPushed, true);
            app.RebuildmodelButton.Text = 'Rebuild model';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AddrunandplotButton = uibutton(app.UIFigure, 'push');
            app.AddrunandplotButton.ButtonPushedFcn = createCallbackFcn(app, @AddrunandplotButtonPushed, true);
            app.AddrunandplotButton.Text = 'Add run and plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = export_code(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end