classdef figure2D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ColormapDropDown              matlab.ui.control.DropDown
        ColormapDropDownLabel         matlab.ui.control.Label
        climmaxEditField              matlab.ui.control.NumericEditField
        climmaxEditFieldLabel         matlab.ui.control.Label
        climminEditField              matlab.ui.control.NumericEditField
        climminEditFieldLabel         matlab.ui.control.Label
        NumberofpointsEditField       matlab.ui.control.NumericEditField
        NumberofpointsEditFieldLabel  matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        SpeciesDropDown               matlab.ui.control.DropDown
        SpeciesDropDownLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        xdata % time
        ydata % concentrations
        names % species names
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, xdata, ydata, names)
            
            % in stochastic simulations we might have the same time points
            % get rid of that for the interpolation
            nonrepeated = [true,diff(xdata)~=0];
            xdata = xdata(nonrepeated);
            ydata = ydata(nonrepeated,:);

            app.xdata = xdata;
            app.ydata = ydata;
            app.names = names;

            app.SpeciesDropDown.Items = names;

            % set the colorbar limits
            app.SpeciesDropDownValueChanged();

            % set colormap list
            colormapList = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', ...
                'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', ...
                'lines', 'colorcube', 'prism', 'flag', 'red', 'green', 'blue', 'yellow'};
            app.ColormapDropDown.Items = colormapList;
            app.ColormapDropDown.Value = 'jet';
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % collect settings
            n = round(app.NumberofpointsEditField.Value); % number of grid points
            ydataPlot = app.ydata(:,strcmp(app.SpeciesDropDown.Value,app.names));
            colormapName = app.ColormapDropDown.Value;
            clims = [app.climminEditField.Value,app.climmaxEditField.Value];

            figure
            % rotate the solution and create a 2D plot
            create_2D_plot(app.xdata,ydataPlot,n,colormapName,clims);
        end

        % Value changed function: SpeciesDropDown
        function SpeciesDropDownValueChanged(app, event)
            nspec = strcmp(app.SpeciesDropDown.Value,app.names);
            nspec = find(nspec==1,1,'first');
            app.climminEditField.Value = min(app.ydata(:,nspec));
            app.climmaxEditField.Value = max(app.ydata(:,nspec));
            if app.climminEditField.Value == app.climmaxEditField.Value
                app.climmaxEditField.Value = app.climminEditField.Value+1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpeciesDropDownLabel = uilabel(app.UIFigure);
            app.SpeciesDropDownLabel.Text = 'Species';
            app.SpeciesDropDown = uidropdown(app.UIFigure);
            app.SpeciesDropDown.Items = {};
            app.SpeciesDropDown.ValueChangedFcn = createCallbackFcn(app, @SpeciesDropDownValueChanged, true);
            app.SpeciesDropDown.Value = {};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.NumberofpointsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofpointsEditFieldLabel.Text = 'Number of points';
            app.NumberofpointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofpointsEditField.Value = 100;
            app.climminEditFieldLabel = uilabel(app.UIFigure);
            app.climminEditFieldLabel.Text = 'clim min';
            app.climminEditField = uieditfield(app.UIFigure, 'numeric');
            app.climmaxEditFieldLabel = uilabel(app.UIFigure);
            app.climmaxEditFieldLabel.Text = 'clim max';
            app.climmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColormapDropDownLabel = uilabel(app.UIFigure);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.UIFigure);
            app.ColormapDropDown.Items = {};
            app.ColormapDropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = figure2D(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end