classdef graph_settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UsetheoriginalgraphSwitch       matlab.ui.control.Switch
        UsetheoriginalgraphSwitchLabel  matlab.ui.control.Label
        SaveCloseButton                 matlab.ui.control.Button
        LoaddefaultButton               matlab.ui.control.Button
        Parameters                      matlab.ui.control.Table
    end

    
    properties (Access = private)
        Ecoli   % Cell object
        MainApp; % main application
        location (1,:) char;% path for the folder of the app
    end
    
    methods (Access = private)
       
        function tf = iscolor(~,c)
            % ISCOLOR checks if input is a valid MATLAB color specification
            % c: input color to check (string, char, or numeric array)
            % tf: true if valid color, false otherwise

            % Define named colors and their RGB equivalents
            namedColors = {'r', 'g', 'b', 'c', 'm', 'y', 'k', 'w', ...
                'red', 'green', 'blue', 'cyan', 'magenta', ...
                'yellow', 'black', 'white'};

            if ischar(c) || isstring(c)
                % Check if it is a named color
                c = char(c);  % Convert to char array for consistent processing
                tf = any(strcmpi(c, namedColors)) || ...
                    (strncmp(c, '#', 1) && length(c) == 7 && all(isstrprop(c(2:end), 'xdigit')));
            elseif isnumeric(c) && numel(c) == 3
                % Check if it is an RGB triplet
                tf = all(c >= 0 & c <= 1);
            else
                tf = false;
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Ecoli)
            
            app.MainApp = mainapp;
            app.Ecoli = Ecoli;

            % path to the folder of the app
            app.location = strrep(mfilename('fullpath'),'graph_settings','');

            % Parameter table
            data = {
                'Node size',Ecoli.data.node_size;
                'Inner regulator size',Ecoli.data.inner_regulator_size;
                'Outer regulator size',Ecoli.data.outer_regulator_size;
                'Protease size',Ecoli.data.protease_size;
                'Protease lineWidth',Ecoli.data.linewidth_protease;
                'Regulator linewidth',Ecoli.data.linewidth_regulator;
                'Node color',Ecoli.data.node_color;
                'Protease color',Ecoli.data.protease_color;
                'Regulation color',Ecoli.data.regulation_color;
                'Regulator color',Ecoli.data.regulator_color;
                'Node fontsize',Ecoli.data.node_fontsize;
                'Node fontweight',Ecoli.data.node_fontweight;
                'Activation edge color',Ecoli.data.act_edge_color;
                'Repression edge color',Ecoli.data.repr_edge_color;
                'Other edge color',Ecoli.data.other_edge_color;
                'Protease edge color',Ecoli.data.protease_edge_color;
                'Layout type',Ecoli.data.layout_type;};
            app.Parameters.Data = data;

            % use the original graph
            if Ecoli.data.original_graph == true
                set(app.UsetheoriginalgraphSwitch,'Value','On')
            else
                set(app.UsetheoriginalgraphSwitch,'Value','Off')
            end
        end

        % Button pushed function: LoaddefaultButton
        function LoaddefaultButtonPushed(app, event)
            % create a new instance with the default graph settings
            default_settings = SharedData(false);
            
            % set the properties in the table
            data = app.Parameters.Data;
            data{1,2} = default_settings.('node_size');
            data{2,2} = default_settings.('inner_regulator_size');
            data{3,2} = default_settings.('outer_regulator_size');
            data{4,2} = default_settings.('protease_size');
            data{5,2} = default_settings.('linewidth_protease');
            data{6,2} = default_settings.('linewidth_regulator');
            data{7,2} = default_settings.('node_color');
            data{8,2} = default_settings.('protease_color');
            data{9,2} = default_settings.('regulation_color');
            data{10,2} = default_settings.('regulator_color');
            data{11,2} = default_settings.('node_fontsize');
            data{12,2} = default_settings.('node_fontweight');
            data{13,2} = default_settings.('act_edge_color');
            data{14,2} = default_settings.('repr_edge_color');
            data{15,2} = default_settings.('other_edge_color');
            data{16,2} = default_settings.('protease_edge_color');
            data{17,2} = default_settings.('layout_type');
            app.Parameters.Data = data;

            % use the original graph
            if default_settings.('original_graph') == true
                set(app.UsetheoriginalgraphSwitch,'Value','On')
            else
                set(app.UsetheoriginalgraphSwitch,'Value','Off')
            end

        end

        % Button pushed function: SaveCloseButton
        function SaveCloseButtonPushed(app, event)
            
            data = app.Parameters.Data;

            % get the properties
            node_size = data{1,2};
            inner_regulator_size = data{2,2};
            outer_regulator_size = data{3,2};
            protease_size = data{4,2};
            linewidth_protease = data{5,2};
            linewidth_regulator = data{6,2};
            node_color = data{7,2};
            protease_color = data{8,2};
            regulation_color = data{9,2};
            regulator_color = data{10,2};
            node_fontsize = data{11,2};
            node_fontweight = data{12,2};
            act_edge_color = data{13,2};
            repr_edge_color = data{14,2};
            other_edge_color = data{15,2};
            protease_edge_color = data{16,2};
            layout_type = data{17,2};

            % use the original graph
            if strcmp(get(app.UsetheoriginalgraphSwitch,'Value'),'On')
                original_graph = true;
            else
                original_graph = false;
            end

            % save it
            save([app.location,'graph_settings.mat'],'node_size','inner_regulator_size', ...
                'outer_regulator_size','protease_size','linewidth_protease', ...
                'linewidth_regulator','node_color','protease_color', ...
                'regulation_color','regulator_color','node_fontsize', ...
                'node_fontweight','act_edge_color','repr_edge_color', ...
                'other_edge_color','protease_edge_color','layout_type','original_graph');

            % set the properties
            app.Ecoli.data.set_graph();

            % update the graph in the main app
            app.MainApp.update_graph();

            % close the app
            delete(app)
        end

        % Cell edit callback: Parameters
        function ParametersCellEdit(app, event)
            indices = event.Indices;
            % if it should be a color
            if any(indices(1) == [7:10,13:16]) && app.iscolor(app.Parameters.Data{indices(1),2})==false
                app.Parameters.Data{indices(1),2} = event.PreviousData;
                fig = uifigure;
                uialert(fig,'A valid color name is necessary!','Invalid Color','CloseFcn', @(~, ~) close(fig))
                waitfor(fig)
                delete(fig)
            end
            % tha layout have specific possible values
            if indices(1) == 17 && ~any(strcmp({'circle', 'force', 'layered', 'subspace', 'force3', 'subspace3'},app.Parameters.Data{indices(1),2}))
                app.Parameters.Data{indices(1),2} = event.PreviousData;
                fig = uifigure;
                uialert(fig,'A valid layout is necessary (circle, force, layered, subspace, force3, subspace3)!','Invalid Layout','CloseFcn', @(~, ~) close(fig))
                waitfor(fig)
                delete(fig)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            % % save the app figure 
            % exportapp(app.UIFigure, 'graph_settings_figure.pdf');

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Parameters = uitable(app.UIFigure);
            app.Parameters.ColumnName = {'Name'; 'Value'};
            app.Parameters.RowName = {};
            app.Parameters.ColumnEditable = [false true];
            app.Parameters.CellEditCallback = createCallbackFcn(app, @ParametersCellEdit, true);
            app.LoaddefaultButton = uibutton(app.UIFigure, 'push');
            app.LoaddefaultButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddefaultButtonPushed, true);
            app.LoaddefaultButton.Text = 'Load default';
            app.SaveCloseButton = uibutton(app.UIFigure, 'push');
            app.SaveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCloseButtonPushed, true);
            app.SaveCloseButton.Text = 'Save & Close';
            app.UsetheoriginalgraphSwitchLabel = uilabel(app.UIFigure);
            app.UsetheoriginalgraphSwitchLabel.Text = 'Use the original graph';
            app.UsetheoriginalgraphSwitch = uiswitch(app.UIFigure, 'slider');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graph_settings(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end