classdef model_selection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SaveandcloseButton         matlab.ui.control.Button
        SelectamodelDropDown       matlab.ui.control.DropDown
        SelectamodelDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % main application
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, prev_model)
            % movethe app in the middle of the screen
            movegui(app.UIFigure,'center');

            % main application
            app.MainApp = mainapp;
            
            % find the folder where we keep the models
            model_folder = which('Tomazou');
            model_names = dir(strrep(model_folder,'Tomazou.m','*.m'));
            model_names = strrep({model_names.name},'.m','');
            app.SelectamodelDropDown.Items = model_names;
            % if ~isfield(app.MainApp,'Ecoli') % if we do not have the cell yet
            %     % default model:
                app.SelectamodelDropDown.Value = prev_model;
            % else
            %     % current model:
            %     app.SelectamodelDropDown.Value = app.MainApp.Ecoli.data.model_name;
            % end

        end

        % Button pushed function: SaveandcloseButton
        function SaveandcloseButtonPushed(app, event)
            % save the setting
            app.MainApp.set_model(app.SelectamodelDropDown.Value);

            % Delete the dialog box
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectamodelDropDownLabel = uilabel(app.UIFigure);
            app.SelectamodelDropDownLabel.Text = 'Select  a model';
            app.SelectamodelDropDown = uidropdown(app.UIFigure);
            app.SelectamodelDropDown.Items = {};
            app.SelectamodelDropDown.Value = {};
            app.SaveandcloseButton = uibutton(app.UIFigure, 'push');
            app.SaveandcloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandcloseButtonPushed, true);
            app.SaveandcloseButton.Text = 'Save and close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = model_selection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end