classdef run_simulations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SaveandexportPanel       matlab.ui.container.Panel
        Figure2DButton           matlab.ui.control.Button
        SaveDataButton           matlab.ui.control.Button
        CreateFigureButton       matlab.ui.control.Button
        Save                     matlab.ui.control.Button
        PlotPanel                matlab.ui.container.Panel
        autocleanSwitch          matlab.ui.control.Switch
        autocleanSwitchLabel     matlab.ui.control.Label
        cleanButton              matlab.ui.control.Button
        Slider_value             matlab.ui.control.NumericEditField
        ValueEditFieldLabel      matlab.ui.control.Label
        rangeLabel               matlab.ui.control.Label
        maximumLabel             matlab.ui.control.Label
        minimumLabel             matlab.ui.control.Label
        range                    matlab.ui.control.NumericEditField
        Slider                   matlab.ui.control.Slider
        Max_value                matlab.ui.control.NumericEditField
        Min_value                matlab.ui.control.NumericEditField
        ParemetersettingPanel    matlab.ui.container.Panel
        RelTol                   matlab.ui.control.NumericEditField
        RelTolEditFieldLabel     matlab.ui.control.Label
        AbsTol                   matlab.ui.control.NumericEditField
        AbsTolEditFieldLabel_2   matlab.ui.control.Label
        Method                   matlab.ui.control.DropDown
        MethodDropDownLabel      matlab.ui.control.Label
        SteadyState              matlab.ui.control.Switch
        workers                  matlab.ui.control.NumericEditField
        workersLabel             matlab.ui.control.Label
        divisions                matlab.ui.control.NumericEditField
        divisionsEditFieldLabel  matlab.ui.control.Label
        ParameterscanButton      matlab.ui.control.Button
        ParameterDropDown        matlab.ui.control.DropDown
        ParameterDropDownLabel   matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp % main application
        Ecoli   % Cell object
        Mobj    % simbiology model
        solver  % solver type
        apphandle struct; % handles for the sub applications
    end
    
    methods (Access = private)
        
        % run simulation
        function simulate(app)

            % run simulation
            [t,c,names] = run_simulation(app.Mobj,app.solver);

            if strcmp(app.autocleanSwitch.Value,'On')
                app.cleanButtonPushed();
            end

            % plot
            hold(app.UIAxes,'on')
            for i = 1:numel(app.Ecoli.data.StatesToLog)
                plot(app.UIAxes,t,c(:,strcmp(names,app.Ecoli.data.StatesToLog{i})))
            end
            xlabel(app.UIAxes,'time')
            ylabel(app.UIAxes,'concentration')
            legend(app.UIAxes,app.Ecoli.data.StatesToLog,'Interpreter','none')
        end

        % set the Slider
        function set_Slider(app)

            % get the parameter
            if ~isempty(app.ParameterDropDown)
                value = get(sbioselect(app.Mobj,'Name',app.ParameterDropDown.Value),'Value');
            else
                return;
            end
            
            app.Min_value.Value = 1/app.range.Value*value;
            if value == 0
                app.Max_value.Value = 1;
            else
                app.Max_value.Value = 2*value-app.Min_value.Value;
            end
            app.Slider.Limits = [app.Min_value.Value,app.Max_value.Value];
            app.Slider.Value = value;
            app.Slider_value.Value = value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Ecoli, solver)
            
            % movethe app in the middle of the screen
            % movegui(app.UIFigure,'center');

            app.MainApp = mainapp;
            app.Ecoli = Ecoli;

            % get the model
            app.Mobj = app.Ecoli.get_model();

            % set the solver
            app.solver = solver;

            switch app.solver
                case {'ode15s','ode45','sundials'} % MATAB sovlers

                    % set the solver
                    set(getconfigset(app.Mobj),'SolverType',app.solver)

                    % accelerate
                    if app.Ecoli.data.Accelerate == true
                        sbioaccelerate(app.Mobj);
                    end

                otherwise % COPASI

                    % get the configset
                    configset = getconfigset(app.Mobj);
                    % convert the model to get only irreversible reactions
                    % it is necessary for stochastic solvers
                    app.Mobj = convert2irrev(app.Mobj);
                    % make extra alterations on the model to be compatible
                    % with COPASI
                    app.Mobj = correct_modifiers(app.Mobj);
                    % set the original configset in the model
                    app.Ecoli.set_configset(app.Mobj,configset)

            end

            % run simulation
            app.simulate();

            % set up parameter list
            % constant parameters and species
            app.ParameterDropDown.Items = [{''};{app.Mobj.Parameters([app.Mobj.Parameters.Constant]==true).Name}.';...
                {app.Mobj.Species.Name}.'];

            % sub application handles
            app.apphandle(1).figure2D = [];

        end

        % Value changed function: Min_value
        function Min_valueValueChanged(app, event)
            value = app.Min_value.Value;
            lim = app.Slider.Limits;
            lim(1) = value;
            app.Slider.Limits = lim;
        end

        % Value changed function: Max_value
        function Max_valueValueChanged(app, event)
            value = app.Max_value.Value;
            lim = app.Slider.Limits;
            lim(2) = value;
            app.Slider.Limits = lim;
        end

        % Value changed function: ParameterDropDown
        function ParameterDropDownValueChanged(app, event)

            % set the Slider
            app.set_Slider();
           
        end

        % Button pushed function: cleanButton
        function cleanButtonPushed(app, event)
            cla(app.UIAxes)
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            if isempty(app.ParameterDropDown.Value)
                return;
            end
            value = app.Slider.Value;
            app.Slider_value.Value = value;
            % the property
            prop = sbioselect(app.Mobj,'Name',app.ParameterDropDown.Value);
            % set the parameter value
            set(prop,'Value',value);

            % run simulation
            app.simulate();
            
        end

        % Value changed function: range
        function rangeValueChanged(app, event)
            if event.Value <= 1 % should be bigger than 1
                app.range.Value = event.PreviousValue;
            else
                app.set_Slider();
            end
        end

        % Value changed function: Slider_value
        function Slider_valueValueChanged(app, event)
            value = app.Slider_value.Value;
            limits = app.Slider.Limits;
            % if the new value is outside of the limit, change the limit
            if value < limits(1)
                limits(1) = value;
                app.Slider.Limits = limits;
                app.Min_value.Value = value;
            elseif value > limits(2)
                limits(2) = value;
                app.Slider.Limits = limits;
                app.Max_value.Value = value;
            end
            % set the Slider
            app.Slider.Value = value;
            SliderValueChanged(app, event)
        end

        % Button pushed function: ParameterscanButton
        function ParameterscanButtonPushed(app, event)
            % if no parameter is selected, just return
            if isempty(app.ParameterDropDown.Value)
                return;
            end

            % number of divisions
            n_divisions = app.divisions.Value;
            % the parameter values
            parameters = linspace(app.Min_value.Value,app.Max_value.Value,n_divisions);

            % the property
            prop = sbioselect(app.Mobj,'Name',app.ParameterDropDown.Value);

            % number of followed species
            nspec = numel(app.Ecoli.data.StatesToLog);

            % final concentration or steady state
            simulation_type = app.SteadyState.Value;

            % settings for SteadyState calculations
            switch simulation_type
                case 'Steady State (slower!)'
                    method = app.Method.Value;
                    abstol = app.AbsTol.Value;
                    reltol = app.RelTol.Value;
            end

            % matrix for the output concentrations
            cout = zeros(n_divisions,nspec);

            Mobj_ = app.Mobj;
            solver_ = app.solver;

            % number of workers
            n_workers = app.workers.Value;
            if n_workers > 1 % parallel computing
                % Get current parallel pool
                p = gcp('nocreate');
                % parallel computing
                if isempty(p) || p.NumWorkers ~= n_workers
                    delete(p);
                    % start a new parallel pool
                    parpool("Processes",n_workers);
                end

                % broadcast variables, they are necessery in parfor even if
                % they are not used
                switch simulation_type
                    case 'Scan final concentration'
                        method = [];
                        abstol = [];
                        reltol = [];
                end

                % go back to the app
                figure(app.UIFigure)

                parfor i = 1:n_divisions
                    % set the property to the given value
                    set(prop,'Value',parameters(i));
                    switch simulation_type
                        case 'Scan final concentration'
                            % run simulation
                            [~,c,~] = run_simulation(Mobj_,solver_,['model',int2str(i),'.smbl']);
                            % store the data
                            cout(i,:) = c(end,:);
                        case 'Steady State (slower!)' % Steady state calculation
                            % calculate steady state
                            [success, ~, Mobj_out] = sbiosteadystate(Mobj_, ...
                                'Method',method,'AbsTol',abstol,'RelTol',reltol);
                            if success == 1
                                % store the data
                                % get the output concentrations for the
                                % followed species from the output of
                                % sbiosteadystate
                                c_SS = get(get(get(getconfigset(Mobj_out),'RuntimeOptions'),'StatesToLog'),'Value');
                                if iscell(c_SS)
                                    cell2mat(c_SS);
                                end
                                cout(i,:) = c_SS;
                            else
                                % store the data
                                cout(i,:) = nan(size(cout(i,:)));
                            end
                    end
                end
            else
                % single thread
                for i = 1:n_divisions
                    % set the property to the given value
                    set(prop,'Value',parameters(i));
                    switch app.SteadyState.Value
                        case 'Scan final concentration'
                            % run simulation
                            [~,c,~] = run_simulation(Mobj_,solver_);
                            % store the data
                            cout(i,:) = c(end,:);
                        case 'Steady State (slower!)' % Steady state calculation
                            % calculate steady state
                            [success, ~, Mobj_out] = sbiosteadystate(Mobj_, ...
                                'Method',method,'AbsTol',abstol,'RelTol',reltol);
                            if success == 1
                                % store the data
                                % get the output concentrations for the
                                % followed species from the output of
                                % sbiosteadystate
                                c_SS = get(get(get(getconfigset(Mobj_out),'RuntimeOptions'),'StatesToLog'),'Value');
                                if iscell(c_SS)
                                    cell2mat(c_SS);
                                end
                                cout(i,:) = c_SS;
                            else
                                % store the data
                                cout(i,:) = nan(size(cout(i,:)));
                            end
                    end

                end
            end

            % clean up the figure
            app.cleanButtonPushed();

            % plot
            hold(app.UIAxes,'on')
            for i = 1:nspec
                plot(app.UIAxes,parameters,cout(:,i))
            end
            xlabel(app.UIAxes,app.ParameterDropDown.Value,'Interpreter','none')
            ylabel(app.UIAxes,'concentration')
            legend(app.UIAxes,app.Ecoli.data.StatesToLog,'Interpreter','none')
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            % if the parameter was in our model
            if ~isempty(sbioselect(app.Ecoli.data.Mobj,'Name',app.ParameterDropDown.Value))
            % new command to execute
            log = {['set(sbioselect(app.Ecoli.data.Mobj,''Name'','''...
                app.ParameterDropDown.Value '''),''Value'','...
                num2str(app.Slider.Value,'%e') ');']};
            elseif ~isempty(app.Ecoli.data.Mobj_extern) &&...
                    ~isempty(sbioselect(app.Ecoli.data.Mobj_extern,'Name',app.ParameterDropDown.Value))
                % new command to execute
                log = {['set(sbioselect(app.Ecoli.data.Mobj_extern,''Name'','''...
                    app.ParameterDropDown.Value '''),''Value'','...
                    num2str(app.Slider.Value,'%e') ');']};
            else
                % unknown parameter, we should not arrive here
                warning('Unknown parameter, we did not set it!')
                return;
            end
            % add the log file to the main app log file
            app.MainApp.update_eval_log(log);
        end

        % Button pushed function: CreateFigureButton
        function CreateFigureButtonPushed(app, event)
            figure;
            hax = axes;
            % copy every children from the UIAxes (since MATLAB2020)
            copyobj(app.UIAxes.Children, hax);
            % legend and labelsí
            hax.XLabel.String = app.UIAxes.XLabel.String;
            hax.YLabel.String = app.UIAxes.YLabel.String;
            legend(hax,'Interpreter','none')
            hax.XAxis.Label.Interpreter = 'none';
            hax.YAxis.Label.Interpreter = 'none';
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            if isempty(app.UIAxes.Children)
                return;
            end
            xdata = app.UIAxes.Children(1).XData;
            ydata = zeros(numel(xdata),numel( app.UIAxes.Children));
            for i = 1:numel( app.UIAxes.Children)
                ydata(:,i) = app.UIAxes.Children(i).YData;
            end
            names = app.UIAxes.Legend.String; %#ok<NASGU> 
            uisave({'xdata','ydata','names'})
        end

        % Button pushed function: Figure2DButton
        function Figure2DButtonPushed(app, event)
            if isempty(app.UIAxes.Children)
                return;
            end
            names = app.UIAxes.Legend.String;
            % we are plotting just the last one
            xdata = app.UIAxes.Children(1).XData;
            ydata = zeros(numel(xdata),numel(names));
            for i = 1:numel(names)
                ydata(:,i) = app.UIAxes.Children(numel(names)-i+1).YData;
            end


            % xdata = app.UIAxes.Children(1).XData;
            % ydata = zeros(numel(xdata),numel( app.UIAxes.Children));
            % for i = 1:numel( app.UIAxes.Children)
            %     ydata(:,i) = app.UIAxes.Children(i).YData;
            % end
            % names = app.UIAxes.Legend.String;

            % open the app
            app.apphandle.figure2D = figure2D(xdata,ydata,names);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            % % save the app figure 
            % exportapp(app.UIFigure, 'run_simulations_figure.pdf');
            % exportgraphics(app.UIAxes, 'run_simulations_axes.pdf');
            
            if isfield(app.apphandle,'figure2D')
                delete(app.apphandle.figure2D)
            end
            delete(app)
        end

        % Value changed function: SteadyState
        function SteadyStateValueChanged(app, event)
            value = app.SteadyState.Value;
            switch value
                case 'Scan final concentration'
                    app.RelTol.Enable = "off";
                    app.AbsTol.Enable = "off";
                    app.Method.Enable = "off";
                case 'Steady State (slower!)'
                    app.RelTol.Enable = "on";
                    app.AbsTol.Enable = "on";
                    app.Method.Enable = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.ParemetersettingPanel = uipanel(app.UIFigure);
            app.ParemetersettingPanel.Title = 'Paremeter setting';
            app.ParameterDropDownLabel = uilabel(app.ParemetersettingPanel);
            app.ParameterDropDownLabel.Text = 'Parameter';
            app.ParameterDropDown = uidropdown(app.ParemetersettingPanel);
            app.ParameterDropDown.Items = {};
            app.ParameterDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterDropDownValueChanged, true);
            app.ParameterDropDown.Value = {};
            app.ParameterscanButton = uibutton(app.ParemetersettingPanel, 'push');
            app.ParameterscanButton.ButtonPushedFcn = createCallbackFcn(app, @ParameterscanButtonPushed, true);
            app.ParameterscanButton.Text = 'Parameter scan';
            app.divisionsEditFieldLabel = uilabel(app.ParemetersettingPanel);
            app.divisionsEditFieldLabel.Text = '#divisions';
            app.divisions = uieditfield(app.ParemetersettingPanel, 'numeric');
            app.divisions.Value = 10;
            app.workersLabel = uilabel(app.ParemetersettingPanel);
            app.workersLabel.Text = '#workers';
            app.workers = uieditfield(app.ParemetersettingPanel, 'numeric');
            app.workers.Value = 1;
            app.SteadyState = uiswitch(app.ParemetersettingPanel, 'slider');
            app.SteadyState.Items = {'Scan final concentration', 'Steady State (slower!)'};
            app.SteadyState.ValueChangedFcn = createCallbackFcn(app, @SteadyStateValueChanged, true);
            app.SteadyState.Value = 'Scan final concentration';
            app.MethodDropDownLabel = uilabel(app.ParemetersettingPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.Method = uidropdown(app.ParemetersettingPanel);
            app.Method.Items = {'auto', 'simulation', 'algebraic'};
            app.Method.Enable = 'off';
            app.Method.Value = 'auto';
            app.AbsTolEditFieldLabel_2 = uilabel(app.ParemetersettingPanel);
            app.AbsTolEditFieldLabel_2.Enable = 'off';
            app.AbsTolEditFieldLabel_2.Text = 'AbsTol';
            app.AbsTol = uieditfield(app.ParemetersettingPanel, 'numeric');
            app.AbsTol.Enable = 'off';
            app.AbsTol.Value = 1e-08;
            app.RelTolEditFieldLabel = uilabel(app.ParemetersettingPanel);
            app.RelTolEditFieldLabel.Enable = 'off';
            app.RelTolEditFieldLabel.Text = 'RelTol';
            app.RelTol = uieditfield(app.ParemetersettingPanel, 'numeric');
            app.RelTol.Enable = 'off';
            app.RelTol.Value = 1e-06;
            app.Min_value = uieditfield(app.UIFigure, 'numeric');
            app.Min_value.ValueChangedFcn = createCallbackFcn(app, @Min_valueValueChanged, true);
            app.Max_value = uieditfield(app.UIFigure, 'numeric');
            app.Max_value.ValueChangedFcn = createCallbackFcn(app, @Max_valueValueChanged, true);
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.range = uieditfield(app.UIFigure, 'numeric');
            app.range.ValueChangedFcn = createCallbackFcn(app, @rangeValueChanged, true);
            app.range.Value = 10;
            app.minimumLabel = uilabel(app.UIFigure);
            app.minimumLabel.Text = 'minimum';
            app.maximumLabel = uilabel(app.UIFigure);
            app.maximumLabel.Text = 'maximum';
            app.rangeLabel = uilabel(app.UIFigure);
            app.rangeLabel.Text = 'range';
            app.ValueEditFieldLabel = uilabel(app.UIFigure);
            app.ValueEditFieldLabel.Text = 'Value';
            app.Slider_value = uieditfield(app.UIFigure, 'numeric');
            app.Slider_value.ValueChangedFcn = createCallbackFcn(app, @Slider_valueValueChanged, true);
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.cleanButton = uibutton(app.PlotPanel, 'push');
            app.cleanButton.ButtonPushedFcn = createCallbackFcn(app, @cleanButtonPushed, true);
            app.cleanButton.Text = 'clean';
            app.autocleanSwitchLabel = uilabel(app.PlotPanel);
            app.autocleanSwitchLabel.Text = 'autoclean';
            app.autocleanSwitch = uiswitch(app.PlotPanel, 'slider');
            app.SaveandexportPanel = uipanel(app.UIFigure);
            app.SaveandexportPanel.Title = 'Save and export';
            app.Save = uibutton(app.SaveandexportPanel, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Text = 'Save setting of the current parameter ';
            app.CreateFigureButton = uibutton(app.SaveandexportPanel, 'push');
            app.CreateFigureButton.ButtonPushedFcn = createCallbackFcn(app, @CreateFigureButtonPushed, true);
            app.CreateFigureButton.Text = 'Create Figure';
            app.SaveDataButton = uibutton(app.SaveandexportPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.Figure2DButton = uibutton(app.SaveandexportPanel, 'push');
            app.Figure2DButton.ButtonPushedFcn = createCallbackFcn(app, @Figure2DButtonPushed, true);
            app.Figure2DButton.Text = 'Figure 2D';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = run_simulations(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end