classdef set_object < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CloseButton             matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        OK                      matlab.ui.control.Button
        CellButton              matlab.ui.control.Button
        RegulatorDropDown       matlab.ui.control.DropDown
        RegulatorDropDownLabel  matlab.ui.control.Label
        ProteaseDropDown        matlab.ui.control.DropDown
        ProteaseDropDownLabel   matlab.ui.control.Label
        NodeDropDown            matlab.ui.control.DropDown
        NodeDropDownLabel       matlab.ui.control.Label
        Rules                   matlab.ui.control.Table
        Reactions               matlab.ui.control.Table
        Parameters              matlab.ui.control.Table
        Species                 matlab.ui.control.Table
    end

    
    properties (Access = public)
        MainApp; % main application
        Ecoli; % the model of the system
        unsaved; % if the current setting is not saved
    end
    
    properties (Access = private)
        current_obj_name % name of the current object
        % log every setting on the cell object, we will do these settings
        % only with the save button
        log 
    end
    
    methods (Access = private)
        % set the tables according to the object properties
        function set_table(app,obj,Property)
            
            n_field = numel(app.(Property).ColumnName);
            % The properties of the given object
            Data = cell(numel(obj.Mobj.(Property)),n_field);
            for i = 1:size(Data,1)
                for j = 1:n_field
                    Data{i,j} = obj.Mobj.(Property)(i).(app.(Property).ColumnName{j});
                end
            end
            app.(Property).Data = Data;
        end

        % set the tables for every properties
        function set_table_all(app,obj)
            
            app.set_table(obj,'Species');
            app.set_table(obj,'Parameters');
            app.set_table(obj,'Reactions');
            app.set_table(obj,'Rules');

        end

        % check wheter the changes are saved
        % if the user want to go back, return 1
        function goback = save_check(app)
            
            % if we have unsaved changes
            if app.unsaved == true
                % if the interaction does not exists, we are not doing anything
                fig = uifigure;
                msg = "There are unsaved changes.";
                title = "Confirm discarding changes";
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Discard Changes","Cancel"], ...
                    "DefaultOption",1,'CloseFcn', @(~, ~) close(fig));
                switch selection
                    case "Discard Changes"
                        app.unsaved = false;
                        goback = false;
                    case "Cancel"
                        goback = true;
                end
                waitfor(fig)
                delete(fig)
            else
                goback = false;
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, ecoli, firstobject)
            
            % movethe app in the middle of the screen
            % movegui(app.UIFigure,'center');
            
            clear set_object;
            
            app.MainApp = mainapp;
            app.Ecoli = ecoli;
            app.log = {};

            % set up the lists
            % nodes
            node_names = fieldnames(app.Ecoli.nodes);
            app.NodeDropDown.Items = [{''};node_names];
            % protease
            protease_names = fieldnames(app.Ecoli.proteases);
            app.ProteaseDropDown.Items = [{''};protease_names];
            % regulators
            % we will use the regulated list. If it contains at lest one
            % '|-' or '<-', then it is a regulator
            % e.g. node |- regulator1 |- regulator2
            isregulator= contains(app.MainApp.regulated_name.Items,{'<-','|-'});
            app.RegulatorDropDown.Items = [{''},app.MainApp.regulated_name.Items(isregulator)];
            app.RegulatorDropDown.ItemsData = [{''},app.MainApp.regulated_name.ItemsData(isregulator)];
            
            % set the input object
            if isa(firstobject,'node')
                app.NodeDropDown.Value = firstobject.name;
                app.NodeDropDownValueChanged();
            elseif isa(firstobject,'protease')
                app.ProteaseDropDown.Value = firstobject.name;
                app.ProteaseDropDownValueChanged();
            elseif isa(firstobject,'regulator')
                app.RegulatorDropDown.Value = firstobject.suffix;
                app.RegulatorDropDownValueChanged();
            end  

            % changes are saved by now
            app.unsaved = false;

        end

        % Value changed function: NodeDropDown
        function NodeDropDownValueChanged(app, event)
            
            % check if there are some unsaved data
            if app.save_check() == 1
                % do not let it change then
                event.Source.Value = event.PreviousValue;
                return;
            end

            value = app.NodeDropDown.Value;
            if ~isempty(value)
                % the other objects are not "active":
                app.ProteaseDropDown.Value = app.ProteaseDropDown.Items{1};
                app.RegulatorDropDown.Value = app.RegulatorDropDown.ItemsData{1};
                % delete the log
                app.log = {};

                % get the properties of this object
                app.current_obj_name = value;
                app.set_table_all(app.Ecoli.nodes.(value));
            end
        end

        % Value changed function: ProteaseDropDown
        function ProteaseDropDownValueChanged(app, event)
            
            % check if there are some unsaved data
            if app.save_check() == 1
                % do not let it change then
                event.Source.Value = event.PreviousValue;
                return;
            end

            value = app.ProteaseDropDown.Value;
            if ~isempty(value)
                % the other objects are not "active":
                app.NodeDropDown.Value = app.NodeDropDown.Items{1};
                app.RegulatorDropDown.Value = app.RegulatorDropDown.ItemsData{1};
                % delete the log
                app.log = {};

                % get the properties of this object
                app.current_obj_name = value;
                app.set_table_all(app.Ecoli.proteases.(value));
            end
        end

        % Value changed function: RegulatorDropDown
        function RegulatorDropDownValueChanged(app, event)
            
            % check if there are some unsaved data
            if app.save_check() == 1
                % do not let it change then
                event.Source.Value = event.PreviousValue;
                return;
            end
            
            value = app.RegulatorDropDown.Value;

            if ~isempty(value)
                % the other objects are not "active":
                app.NodeDropDown.Value = app.NodeDropDown.Items{1};
                app.ProteaseDropDown.Value = app.ProteaseDropDown.Items{1};
                % delete the log
                app.log = {};

                % get the properties of this object
                app.current_obj_name = value;
                obj_name = strrep(app.Ecoli.route2fieldnames(value),'cell','app.Ecoli');
                app.set_table_all(eval(obj_name));
            end
        end

        % Button pushed function: CellButton
        function CellButtonPushed(app, event)
            % the other objects are not "active":
            app.NodeDropDown.Value = app.NodeDropDown.Items{1};
            app.ProteaseDropDown.Value = app.ProteaseDropDown.Items{1};
            app.RegulatorDropDown.Value = app.RegulatorDropDown.ItemsData{1};
            % delete the log
            app.log = {};

            % the name will be 'Cell'
            app.current_obj_name = 'Cell';
            
            %  set the properties from SharedData
            n_field = numel(app.Parameters.ColumnName);
            Common = cell(2,n_field);
            for j = 1:n_field
                 Common{1,j} = app.Ecoli.data.volume.(app.Parameters.ColumnName{j});
            end
            app.Parameters.Data = Common;
            % everything else is empty
            app.Species.Data = [];
            app.Reactions.Data = [];
            app.Rules.Data = [];

        end

        % Cell edit callback: Species
        function SpeciesCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            % set the appropriate property first in the log file
            obj_name = app.current_obj_name;
            obj_name = strcat(',''',obj_name,'''');
            if iscell(obj_name)
                obj_name = [obj_name{:}];
            end
            app.log{end+1,1} = ['app.Ecoli.set(''' ...
                app.Species.Data{indices(1),1} ''',''' app.Species.ColumnName{indices(2)} ''',' ...
                num2str(newData,'%e')  obj_name ');'];
            
        end

        % Button pushed function: OK
        function OKButtonPushed(app, event)
            % save
            app.SaveButtonPushed();
            % close
            app.CloseButtonPushed();
        end

        % Cell edit callback: Parameters
        function ParametersCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if strcmp(app.current_obj_name,'Cell')
                % common cell parametres
                app.log{end+1,1} = ['set(app.Ecoli.data.(''' ...
                    app.Parameters.Data{indices(1),1} '''),''' app.Parameters.ColumnName{indices(2)} ''',' ...
                    num2str(newData,'%e') ');'];
            else
                % set the appropriate property first in the log file
                obj_name = app.current_obj_name;
                obj_name = strcat(',''',obj_name,'''');
                if iscell(obj_name)
                    obj_name = [obj_name{:}];
                end
                app.log{end+1,1} = ['app.Ecoli.set(''' ...
                    app.Parameters.Data{indices(1),1} ''',''' app.Parameters.ColumnName{indices(2)} ''',' ...
                    num2str(newData,'%e')  obj_name ');'];
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            % check if there are some unsaved data
            if app.save_check() == 1
                return;
            end
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % evaulate every logged modification
            for i = 1:numel(app.log)
                eval(app.log{i});
            end
            % add the log file to the main app log file
            app.MainApp.update_log(app.log);
            % reset the log
            app.log = {};

            % saved changes
            app.unsaved = false;

            % give back the model to the main application
            % it is not necessary if we are changing just handle objects
%             app.MainApp.update_Ecoli(app.Ecoli);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % check if there are some unsaved data
            if app.save_check() == 1
                return;
            end
            % % save the app figure 
            % exportapp(app.UIFigure, 'set_object_figure.pdf');
            delete(app)
        end

        % Display data changed function: Species
        function SpeciesDisplayDataChanged(app, event)
            % unsaved changes
            app.unsaved = true;
        end

        % Display data changed function: Parameters
        function ParametersDisplayDataChanged(app, event)
            % unsaved changes
            app.unsaved = true;
        end

        % Display data changed function: Reactions
        function ReactionsDisplayDataChanged(app, event)
            % unsaved changes
            app.unsaved = true;
        end

        % Display data changed function: Rules
        function RulesDisplayDataChanged(app, event)
            % unsaved changes
            app.unsaved = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Species = uitable(app.UIFigure);
            app.Species.ColumnName = {'Name'; 'InitialAmount'; 'Constant'; 'Units'; 'Notes'};
            app.Species.RowName = {};
            app.Species.ColumnEditable = [false true false false false];
            app.Species.CellEditCallback = createCallbackFcn(app, @SpeciesCellEdit, true);
            app.Species.DisplayDataChangedFcn = createCallbackFcn(app, @SpeciesDisplayDataChanged, true);
            app.Parameters = uitable(app.UIFigure);
            app.Parameters.ColumnName = {'Name'; 'Value'; 'Constant'; 'Units'; 'Notes'};
            app.Parameters.RowName = {};
            app.Parameters.ColumnEditable = [false true false false false];
            app.Parameters.CellEditCallback = createCallbackFcn(app, @ParametersCellEdit, true);
            app.Parameters.DisplayDataChangedFcn = createCallbackFcn(app, @ParametersDisplayDataChanged, true);
            app.Reactions = uitable(app.UIFigure);
            app.Reactions.ColumnName = {'Reaction'; 'ReactionRate'};
            app.Reactions.RowName = {};
            app.Reactions.ColumnEditable = [false false];
            app.Reactions.DisplayDataChangedFcn = createCallbackFcn(app, @ReactionsDisplayDataChanged, true);
            app.Rules = uitable(app.UIFigure);
            app.Rules.ColumnName = {'Name'; 'Rule'; 'RuleType'};
            app.Rules.RowName = {};
            app.Rules.DisplayDataChangedFcn = createCallbackFcn(app, @RulesDisplayDataChanged, true);
            app.NodeDropDownLabel = uilabel(app.UIFigure);
            app.NodeDropDownLabel.Text = 'Node';
            app.NodeDropDown = uidropdown(app.UIFigure);
            app.NodeDropDown.Items = {};
            app.NodeDropDown.ValueChangedFcn = createCallbackFcn(app, @NodeDropDownValueChanged, true);
            app.NodeDropDown.Value = {};
            app.ProteaseDropDownLabel = uilabel(app.UIFigure);
            app.ProteaseDropDownLabel.Text = 'Protease';
            app.ProteaseDropDown = uidropdown(app.UIFigure);
            app.ProteaseDropDown.Items = {};
            app.ProteaseDropDown.ValueChangedFcn = createCallbackFcn(app, @ProteaseDropDownValueChanged, true);
            app.ProteaseDropDown.Value = {};
            app.RegulatorDropDownLabel = uilabel(app.UIFigure);
            app.RegulatorDropDownLabel.Text = 'Regulator';
            app.RegulatorDropDown = uidropdown(app.UIFigure);
            app.RegulatorDropDown.Items = {};
            app.RegulatorDropDown.ValueChangedFcn = createCallbackFcn(app, @RegulatorDropDownValueChanged, true);
            app.RegulatorDropDown.Value = {};
            app.CellButton = uibutton(app.UIFigure, 'push');
            app.CellButton.ButtonPushedFcn = createCallbackFcn(app, @CellButtonPushed, true);
            app.CellButton.Text = 'Cell';
            app.OK = uibutton(app.UIFigure, 'push');
            app.OK.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OK.Text = 'Save and close';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = set_object(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end