classdef set_simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Tree                       matlab.ui.container.CheckBoxTree
        DimensionalanalysisNode    matlab.ui.container.TreeNode
        AccelerateNode             matlab.ui.container.TreeNode
        FollowspeciesinsimulationsPanel  matlab.ui.container.Panel
        DeleteButton               matlab.ui.control.Button
        AddButton                  matlab.ui.control.Button
        StatesToLogListBox         matlab.ui.control.ListBox
        StatesToLogListBoxLabel    matlab.ui.control.Label
        SelectSpeciesListBox       matlab.ui.control.ListBox
        SelectSpeciesListBoxLabel  matlab.ui.control.Label
        SolverTypeDropDown         matlab.ui.control.DropDown
        SolverTypeDropDownLabel    matlab.ui.control.Label
        Parameters                 matlab.ui.control.Table
        CloseButton                matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        OK                         matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainApp; % main application
        Ecoli; % the model of the system
    end
    
    properties (Access = private)
        % log every setting on the cell object, we will do these settings
        % only with the save button
        log
    end
    
    methods (Access = private)
        
        % set output time points if necessary
        function [] = set_OutputTimes(app)
            % number of output time points
            n_out = app.Parameters.Data{strcmp(app.Parameters.Data(:,1),'TimePoints (0:auto)'),2};
            if str2double(n_out)==0
                % automatic, nothing to do
                return;
            end
            % set the time points
            t_start = app.Parameters.Data{strcmp(app.Parameters.Data(:,1),'StartTime'),2};
            t_end = app.Parameters.Data{strcmp(app.Parameters.Data(:,1),'StopTime'),2};
            app.log{end+1,1} = ['set(get(getconfigset(app.Ecoli.data.Mobj),''SolverOptions''),''OutputTimes'',linspace(' t_start ',' t_end ',' n_out '));'];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, ecoli, solver)
            
            % movethe app in the middle of the screen
            % movegui(app.UIFigure,'center');
            
            app.MainApp = mainapp;
            app.Ecoli = ecoli;
            app.log = {};

            % configset object
            configsetobj = getconfigset(app.Ecoli.data.Mobj);

            % set the list of the species
            if isvalid(app.Ecoli.data.Mobj_extern)
                app.SelectSpeciesListBox.Items = {app.Ecoli.data.Mobj.Species.Name,...
                    app.Ecoli.data.Mobj_extern.Species.Name};
            else
                app.SelectSpeciesListBox.Items = {app.Ecoli.data.Mobj.Species.Name};
            end
            app.StatesToLogListBox.Items = app.Ecoli.data.StatesToLog;

            % set the solver type
            app.SolverTypeDropDown.Value = solver;

            % Tree
            active = [configsetobj.CompileOptions.Dimensionalanalysis,...
                app.Ecoli.data.Accelerate];
            if any(active)
                app.Tree.CheckedNodes = app.Tree.Children(active);
            else
                app.Tree.CheckedNodes = [];
            end
            
            % if we have 'OutputTimes', the 'StartTime' will be set based
            % on that
            times = configsetobj.SolverOptions.OutputTimes;
            if numel(times)~=0
                t_start = min(times);
            else
                t_start = configsetobj.StartTime;
            end
            % Parameter table
            data = {
                'StartTime',num2str(t_start,'%e');
                'StopTime',num2str(configsetobj.StopTime,'%e');
                'TimePoints (0:auto)',num2str(numel(configsetobj.SolverOptions.OutputTimes),'%e');
                'MaximumWallClock',num2str(configsetobj.MaximumWallClock,'%e');
                'AbsoluteTolerance',num2str(configsetobj.SolverOptions.AbsoluteTolerance,'%e');
                'RelativeTolerance',num2str(configsetobj.SolverOptions.RelativeTolerance,'%e')};
            app.Parameters.Data = data;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % add the log file to the main app log file
            app.MainApp.update_eval_log(app.log);
            % reset the log
            app.log = {};
        end

        % Button pushed function: OK
        function OKButtonPushed(app, event)
            % save
            app.SaveButtonPushed();
            % close
            app.CloseButtonPushed();
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            % add the selected value
            if ~isempty(app.SelectSpeciesListBox.Value) && ~any(strcmp(app.SelectSpeciesListBox.Value,app.StatesToLogListBox.Items))
                app.StatesToLogListBox.Items = [app.StatesToLogListBox.Items, app.SelectSpeciesListBox.Value];
                app.log{end+1,1} = ['app.Ecoli.data.StatesToLog = [app.Ecoli.data.StatesToLog, {''' app.SelectSpeciesListBox.Value '''}];'];
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            % delete the selected value
            deleted_name = app.StatesToLogListBox.Value;
            if ~isempty(deleted_name)
                app.StatesToLogListBox.Items(strcmp(deleted_name,app.StatesToLogListBox.Items)) = [];
                app.log{end+1,1} = ['app.Ecoli.data.StatesToLog(strcmp(app.Ecoli.data.StatesToLog,''' deleted_name ''')) = [];'];
            end
        end

        % Value changed function: SolverTypeDropDown
        function SolverTypeDropDownValueChanged(app, event)
            value = app.SolverTypeDropDown.Value;
            % app.log{end+1,1} = ['set(getconfigset(app.Ecoli.data.Mobj),''SolverType'',''' value ''');'];
            % app.log{end+1,1} = ['app.Ecoli.data.SolverType = ''' value ''';'];
            app.log{end+1,1} = ['app.solver = ''' value ''';'];
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = [];
            prevcheckedNodes = [];
            if ~isempty(app.Tree.CheckedNodes)
                checkedNodes = {app.Tree.CheckedNodes.Text};
            end
            if ~isempty(event.PreviousCheckedNodes)
                prevcheckedNodes = {event.PreviousCheckedNodes.Text};
            end

            % find the button which was changed and add the change to the
            % log
            if numel(prevcheckedNodes) > numel(checkedNodes)
                % deleted
                for i = 1:numel(prevcheckedNodes)
                    if ~any(strcmp( prevcheckedNodes{i},checkedNodes ))
                        switch prevcheckedNodes{i}
                            case 'Dimensionalanalysis'
                                app.log{end+1,1} = 'set(get(getconfigset(app.Ecoli.data.Mobj),''CompileOptions''),''Dimensionalanalysis'',false)';
                            case 'Accelerate'
                                app.log{end+1,1} = 'app.Ecoli.data.Accelerate = false;';
                        end
                        return;
                    end
                end
            else
                % added
                for i = 1:numel(checkedNodes)
                    if ~any(strcmp( checkedNodes{i},prevcheckedNodes ))
                        switch checkedNodes{i}
                            case 'Dimensionalanalysis'
                                app.log{end+1,1} = 'set(get(getconfigset(app.Ecoli.data.Mobj),''CompileOptions''),''Dimensionalanalysis'',true)';
                            case 'Accelerate'
                                app.log{end+1,1} = 'app.Ecoli.data.Accelerate = true;';
                        end
                        return;
                    end
                end
            end

        end

        % Cell edit callback: Parameters
        function ParametersCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            % set the property in the log file
            % app.log{end+1,1} = ['app.Ecoli.data.' app.Parameters.Data{indices(1),1} ' = ' ...
            %     num2str(newData,'%e') ';'];
            parameter_name = app.Parameters.Data{indices(1),1};
            switch parameter_name
                case 'TimePoints (0:auto)'
                    if newData==0
                        % automatic time points
                        app.log{end+1,1} = 'set(get(getconfigset(app.Ecoli.data.Mobj),''SolverOptions''),''OutputTimes'',zeros(0,1));';
                    else
                        % set output time points
                        app.set_OutputTimes();
                    end
                case 'StopTime'
                    app.log{end+1,1} = ['set(getconfigset(app.Ecoli.data.Mobj),''StopTime'','...
                        num2str(newData,'%e') ');'];
                    % set output time points if necessary
                    app.set_OutputTimes();
                case 'StartTime'
                    % 'StartTime' is read only, we can change the
                    % 'OutputTimes' accordingly
                    % number of output time points
                    n_out = app.Parameters.Data{strcmp(app.Parameters.Data(:,1),'TimePoints (0:auto)'),2};
                    if str2double(n_out)==0
                        % if it was zero, lets change it to 1000
                        app.Parameters.Data{strcmp(app.Parameters.Data(:,1),'TimePoints (0:auto)'),2} = num2str(1e3,'%e');
                    end
                    % the stop time should be larger
                    t_end = app.Parameters.Data{strcmp(app.Parameters.Data(:,1),'StopTime'),2};
                    newData = str2double(newData);
                    if str2double(t_end) <= newData
                        app.Parameters.Data{strcmp(app.Parameters.Data(:,1),'StopTime'),2} = num2str(newData+1,'%e');
                    end
                    % set output time points
                    app.set_OutputTimes();
                case 'MaximumWallClock'
                    app.log{end+1,1} = ['set(getconfigset(app.Ecoli.data.Mobj),''MaximumWallClock'','...
                        num2str(newData,'%e') ');'];
                otherwise
                    app.log{end+1,1} = ['set(get(getconfigset(app.Ecoli.data.Mobj),''SolverOptions''),''',...
                parameter_name,''',' num2str(newData,'%e') ');'];
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % % save the app figure 
            % exportapp(app.UIFigure, 'set_simulation_figure.pdf');
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OK = uibutton(app.UIFigure, 'push');
            app.OK.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OK.Text = 'Save and close';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Parameters = uitable(app.UIFigure);
            app.Parameters.ColumnName = {'Parameter'; 'Value'};
            app.Parameters.RowName = {};
            app.Parameters.ColumnEditable = true;
            app.Parameters.CellEditCallback = createCallbackFcn(app, @ParametersCellEdit, true);
            app.SolverTypeDropDownLabel = uilabel(app.UIFigure);
            app.SolverTypeDropDownLabel.Text = 'SolverType';
            app.SolverTypeDropDown = uidropdown(app.UIFigure);
            app.SolverTypeDropDown.Items = {'ode15s', 'ode45', 'sundials', 'Deterministic (LSODA)', 'Deterministic (RADAU5)', 'Stochastic (Adaptive SSA/T-leap)', 'Stochastic (Gibson + Bruck)', 'Stochastic (Direct method)', 'Stochastic (T-leap)', 'Hybrid (Runge-Kutta)', 'Hybrid (LSODA)', 'Hybrid (RK-45)', 'SDE Solver (RI5)'};
            app.SolverTypeDropDown.ItemsData = {'ode15s', 'ode45', 'sundials', 'lsoda', 'radau5', 'adaptivesa', 'stochastic', 'directMethod', 'tauleap', 'hybrid', 'hybridlsoda', 'hybridode45', 'sde'};
            app.SolverTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SolverTypeDropDownValueChanged, true);
            app.SolverTypeDropDown.Value = 'ode15s';
            app.FollowspeciesinsimulationsPanel = uipanel(app.UIFigure);
            app.FollowspeciesinsimulationsPanel.Title = 'Follow species in simulations';
            app.SelectSpeciesListBoxLabel = uilabel(app.FollowspeciesinsimulationsPanel);
            app.SelectSpeciesListBoxLabel.Text = 'Select Species';
            app.SelectSpeciesListBox = uilistbox(app.FollowspeciesinsimulationsPanel);
            app.SelectSpeciesListBox.Items = {''};
            app.SelectSpeciesListBox.Value = '';
            app.StatesToLogListBoxLabel = uilabel(app.FollowspeciesinsimulationsPanel);
            app.StatesToLogListBoxLabel.Text = 'StatesToLog';
            app.StatesToLogListBox = uilistbox(app.FollowspeciesinsimulationsPanel);
            app.StatesToLogListBox.Items = {''};
            app.StatesToLogListBox.Value = '';
            app.AddButton = uibutton(app.FollowspeciesinsimulationsPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.FollowspeciesinsimulationsPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.DimensionalanalysisNode = uitreenode(app.Tree);
            app.DimensionalanalysisNode.Text = 'Dimensionalanalysis';
            app.AccelerateNode = uitreenode(app.Tree);
            app.AccelerateNode.Text = 'Accelerate';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.DimensionalanalysisNode];
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = set_simulation(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end