classdef spatial_simulations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SimulationsettingsPanel      matlab.ui.container.Panel
        SavedataButton               matlab.ui.control.Button
        BoundaryconditionsLabel      matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        PrelimSimCheckBox            matlab.ui.control.CheckBox
        RightDropDown                matlab.ui.control.DropDown
        RightDropDownLabel           matlab.ui.control.Label
        LeftDropDown                 matlab.ui.control.DropDown
        LeftDropDownLabel            matlab.ui.control.Label
        DownDropDown                 matlab.ui.control.DropDown
        DownDropDownLabel            matlab.ui.control.Label
        UpDropDown                   matlab.ui.control.DropDown
        UpDropDownLabel              matlab.ui.control.Label
        SimulateButton               matlab.ui.control.Button
        ColonypropertiesPanel        matlab.ui.container.Panel
        colony_diff                  matlab.ui.control.NumericEditField
        spatialdivisionsinyLabel_11  matlab.ui.control.Label
        growthrate                   matlab.ui.control.NumericEditField
        spatialdivisionsinyLabel_10  matlab.ui.control.Label
        height_app                   matlab.ui.control.NumericEditField
        spatialdivisionsinyLabel_9   matlab.ui.control.Label
        sigma_app                    matlab.ui.control.NumericEditField
        spatialdivisionsinyLabel_8   matlab.ui.control.Label
        cmax_app                     matlab.ui.control.NumericEditField
        spatialdivisionsinyLabel_7   matlab.ui.control.Label
        symtype_app                  matlab.ui.control.Switch
        SimulationtypeSwitchLabel    matlab.ui.control.Label
        noise_app                    matlab.ui.control.NumericEditField
        spatialdivisionsinyLabel_6   matlab.ui.control.Label
        nplot_app                    matlab.ui.control.NumericEditField
        spatialdivisionsinyLabel_5   matlab.ui.control.Label
        Ly_app                       matlab.ui.control.NumericEditField
        spatialdivisionsinyLabel_4   matlab.ui.control.Label
        nt_app                       matlab.ui.control.NumericEditField
        spatialdivisionsinyLabel_3   matlab.ui.control.Label
        T_app                        matlab.ui.control.NumericEditField
        spatialdivisionsinyLabel_2   matlab.ui.control.Label
        nx1_app                      matlab.ui.control.NumericEditField
        spatialdivisionsinyLabel     matlab.ui.control.Label
        nx2_app                      matlab.ui.control.NumericEditField
        spatialdivisionsinxLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        Ecoli   % Cell object
        Simdata % result of the simulation
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ecoli)
            app.Ecoli = ecoli;

            % content of the diffusion table
            Data = cell(numel(app.Ecoli.data.Mobj.Species),3);
            Data(:,1) = {app.Ecoli.data.Mobj.Species.Name};
            for i = 1:size(Data,1)
                Data{i,2} = 0;
                % by defalut species with 'molecule' unit move with the
                % colony
                if strcmp(app.Ecoli.data.Mobj.Species(i).Unit,'molecule')
                    Data{i,3} = true;
                else
                    Data{i,3} = false;
                end
            end
            app.UITable.Data = Data;

        end

        % Value changed function: symtype_app
        function symtype_appValueChanged(app, event)
            value = app.symtype_app.Value;
            switch value
                case 'Lawn of cells'
                    app.ColonypropertiesPanel.Enable = "off";
                    app.UITable.ColumnEditable(end) = false;
                case 'Colony growth'
                    app.ColonypropertiesPanel.Enable = "on";
                    app.UITable.ColumnEditable(end) = true;
            end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)

            % there is nothing to save before the simulation
            app.SavedataButton.Enable = "off";

            % get the model
            Mobj = app.Ecoli.get_model();

            % we start with a preliminary reaction kinetics simulation
            % do determine the initial conditions
            if app.PrelimSimCheckBox.Value == true
                % set simulation time
                % set(getconfigset(Mobj),'StopTime',1e3);
                % we have to follow every species
                set(get(getconfigset(Mobj),'RuntimeOptions'),'StatesToLog',{Mobj.Species.Name})
                [~,c,~] = sbiosimulate(Mobj);
                % set output concentration for Mobj
                for i = 1:numel(Mobj.Species)
                    Mobj.Species(i).InitialAmount = c(end,i);
                end
            end

            % new variable for colony simulations
            if strcmp(app.symtype_app.Value,'Colony growth')
                colony = true;
            else
                colony = false;
            end

            if colony == true
                % add cell growth with logisctic growth rate
                addreaction(Mobj, 'null -> C','ReactionRate','k_cell*C*(C_unit-C)');
                addparameter(Mobj,'C_unit',1,'Units','molecule'); % just to set up the units
                addparameter(Mobj,'k_cell',app.growthrate.Value,'Units','1/(molecule*minute)');
                set(sbioselect(Mobj.Species,'Name','C'),'Units','molecule')
            end

            %% Parameters

            % number of divisions in "y" direction -> n+1 grid points
            nx1 = app.nx1_app.Value;
            % number of divisions in "x" direction -> n+1 grid points
            nx2 = app.nx2_app.Value;
            nt = app.nt_app.Value; % time steps
            L1 = app.Ly_app.Value; % length in "y" direction
            T = app.T_app.Value; % simulation time

            % number of species
            n_species = numel(Mobj.Species);

            % diffusion coefficient for the different species
            D = [app.UITable.Data{:,2}].';
            if colony == true
                % cell diffusion coefficient
                D(end+1) = app.colony_diff.Value;
                % reaction zone for cells
                cmax = app.cmax_app.Value;
                % check wheter a species moves with the colony (inside the cell) or not
                innercolony_species = logical([app.UITable.Data{:,3}].');
            end

            h = L1/nx1;
            dt = T/nt;
            L2 = h*nx2;

            % number of plots during the simulation
            n_plot = app.nplot_app.Value;

            % noise strength
            noise = app.noise_app.Value;

            %% Boundary conditions

            % upper bounary: 'noflux', 'pbc' or 'dirichlet'
            b.Boundary_up = app.UpDropDown.Value;
            % down bounary: 'noflux', 'pbc' or 'dirichlet'
            b.Boundary_down = app.DownDropDown.Value;
            % left bounary: 'noflux', 'pbc' or 'dirichlet'
            b.Boundary_left = app.LeftDropDown.Value;
            % right bounary: 'noflux', 'pbc' or 'dirichlet'
            b.Boundary_right = app.RightDropDown.Value;

            %% initial condition

            % cell for the concentration matrices
            c  = cell(n_species,1);

            if colony == true
                % parameters of the initial gauss curved colony
                sigma = app.sigma_app.Value/h;
                height = app.height_app.Value;

                % center position
                center = round([nx1/2+1,nx2/2+1]);
                % create gaussian cell distribution
                G = gaussian2D(nx1+1, nx2+1, center, sigma, height);

                c{end} = reshape(G,[numel(G),1]);
            end

            % with the colony simulation we do not need new initial condition for the
            % last speces, for the colony => n_species-1 (if colony==true)
            for i = 1:n_species-colony
                c{i} = (Mobj.Species(i).Value*(ones((nx1+1)*(nx2+1),1)+noise*(rand((nx1+1)*(nx2+1),1)-.5)));
            end
            clear G

            %% building the matrices

            % matrices for gradient
            if colony == true
                [Ax,Ay] = grad_matrices(nx1,nx2,b,h);
            end

            % Laplace: L = Axx + Ayy;
            [Axx,Ayy] = laplace_matrices(nx1,nx2,b,h);

            %% Laplace ADI
            % ADI method for the diffusion of the amino acids

            % store the matrices separately for every diffusion coefficient
            % this is not memory efficient, but we need LU decomposition only once
            % (fast)

            % unique diffusion coefficients and the position for the matrices for the
            % individual species:
            [D_unique,~,D_pos] = unique(D);
            % number of the unique diffusion coefficients
            n_diff = length(D_unique);

            % M: Lxx, Uxx, Lyy, Uyy, Aexx, Aeyy
            M = cell(n_diff,6);

            % unit matrix
            E = speye((nx1+1)*(nx2+1));

            for i = 1:n_diff

                % implicit matrix:
                Aixx = E-D_unique(i)*dt/2*Axx;
                Aiyy = E-D_unique(i)*dt/2*Ayy;
                % LU decomposition:
                [Lxx,Uxx] = lu(Aixx);
                [Lyy,Uyy] = lu(Aiyy);

                % explicit matrix:
                Aexx = E+D_unique(i)*dt/2*Axx;
                Aeyy = E+D_unique(i)*dt/2*Ayy;

                % store the matrices
                M{i,1} = Lxx;
                M{i,2} = Uxx;
                M{i,3} = Lyy;
                M{i,4} = Uyy;
                M{i,5} = Aexx;
                M{i,6} = Aeyy;

            end

            clear Axx Ayy E Aixx Aiyy

            %% Reaction kinetics settings

            % get the constant parameters from the model
            p = get_parameters(Mobj,'k_ext');

            % generate ODE function from the model
            if colony == true
                % active zone just in a given range
                removed_species = simbio2ode(Mobj,'generated_model','range');
            else
                % we go wor full range all the time
                removed_species = simbio2ode(Mobj,'generated_model','cell');
            end

            %% Simulation

            % followed species names
            followed_names = app.Ecoli.data.StatesToLog;
            % serial number of the followed species
            followed = find(contains({Mobj.Species.Name},app.Ecoli.data.StatesToLog));
            if colony == true
                % we follow the cell growth
                followed_names{end+1} = 'C';
                followed(end+1) = n_species;
            end
            % % serial number of the followed species
            % followed = [find(contains({Mobj.Species.Name},app.Ecoli.data.StatesToLog)),n_species];


            % size of the tiledlayout
            nplot1 = floor(sqrt(length(followed)));
            nplot2 = ceil(sqrt(length(followed)));
            while nplot1*nplot2 < length(followed)
                nplot2 = nplot2+1;
            end

            % store the handles for the plot
            h_plot = cell(length(followed),1);

            hf = figure;
            set(hf,'Position',[104         189        1710         872])
            tiledlayout(nplot1,nplot2,'TileSpacing','tight');
            for nplot = 1:length(followed)
                nexttile
                % for colony growth we always follow the colony
                if colony==false || nplot==length(followed)
                    h_plot{nplot} = nextsurf(reshape(c{followed(nplot)},[nx1+1,nx2+1]),L1,L2,nx1,nx2,['[' followed_names{nplot} ']']);
                else
                    h_plot{nplot} = nextsurf(reshape(c{followed(length(followed))}.*c{followed(nplot)},[nx1+1,nx2+1]),L1,L2,nx1,nx2,['[C] [' followed_names{nplot} ']']);
                end

            end
            drawnow

            tic
            for i = 1:nt

                % === Diffusion ===
                if colony == true
                    % D*dt*Nabla(cell)/cell
                    Ncellx = D(end)*dt*(Ax*c{end})./(c{end}+1e-6);
                    Ncelly = D(end)*dt*(Ay*c{end})./(c{end}+1e-6);
                end

                for j = 1:n_species
                    % if the diffusion coefficient is not zero
                    if D(j) ~= 0
                        % impl: L(U*c) = c
                        % x:expl, y:impl
                        c{j} = M{D_pos(j),3}\(M{D_pos(j),5}*c{j});
                        c{j} = M{D_pos(j),4}\c{j};
                        % x:impl, y:expl
                        c{j} = M{D_pos(j),1}\(M{D_pos(j),6}*c{j});
                        c{j} = M{D_pos(j),2}\c{j};
                    elseif colony == true && innercolony_species(j) == true
                        % non diffusible species which are in the cell and move with it
                        c{j} = c{j}+(Ax*c{j}).*Ncellx+(Ay*c{j}).*Ncelly;
                    end
                end


                % === Reactions ===

                if colony == true
                    % active zone
                    R = c{end}<=cmax;

                    % if a species is set by rules
                    if any(removed_species)
                        c_rules = generated_model_rule(i*dt, c, p, R);
                        c{removed_species}(R) = c_rules{removed_species}(R);
                    end
                    dc = generated_model(i*dt, c, p, R);
                    for j = 1:n_species
                        c{j}(R) = c{j}(R) + dc{j}*dt;
                    end
                else
                    % for the cell growth we do not need the active zone (active
                    % everywhere)
                    dc = generated_model(i*dt, c, p);
                    for j = 1:n_species
                        c{j} = c{j} + dc{j}*dt;
                    end
                end

                % plot the data
                if rem(i,nt/n_plot) == 0

                    % update the surf
                    for nplot = 1:length(followed)
                        if colony==false || nplot==length(followed)
                            % last one is always the colony
                            set(h_plot{nplot},'ZData',reshape(c{followed(nplot)},[nx1+1,nx2+1]));
                        else
                            set(h_plot{nplot},'ZData',reshape(c{followed(length(followed))}.*c{followed(nplot)},[nx1+1,nx2+1]));
                        end
                    end
                    drawnow

                    % hatralevo ido:
                    disp([int2str(i/nt*100) '%, estimated remaining time: ' ...
                        num2str(toc/i*(nt-i),'%4.2f')])
                end

            end
            toc
            
            % now we have something to save
            app.SavedataButton.Enable = "on";
            % reshape the data to a matrix from
            for i = 1:numel(c)
                c{i} = reshape(c{i},[nx1+1,nx2+1]);
            end
            app.Simdata = c;
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            c = app.Simdata; %#ok<NASGU>
            uisave('c')
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            % save the app figure 
            % exportapp(app.UIFigure, 'spatial_simulations.pdf');

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SimulationsettingsPanel = uipanel(app.UIFigure);
            app.SimulationsettingsPanel.Title = 'Simulation settings';
            app.spatialdivisionsinxLabel = uilabel(app.SimulationsettingsPanel);
            app.spatialdivisionsinxLabel.Text = '#spatial divisions in "x"';
            app.nx2_app = uieditfield(app.SimulationsettingsPanel, 'numeric');
            app.nx2_app.RoundFractionalValues = 'on';
            app.nx2_app.Value = 100;
            app.spatialdivisionsinyLabel = uilabel(app.SimulationsettingsPanel);
            app.spatialdivisionsinyLabel.Text = '#spatial divisions in "y"';
            app.nx1_app = uieditfield(app.SimulationsettingsPanel, 'numeric');
            app.nx1_app.RoundFractionalValues = 'on';
            app.nx1_app.Value = 100;
            app.spatialdivisionsinyLabel_2 = uilabel(app.SimulationsettingsPanel);
            app.spatialdivisionsinyLabel_2.Text = 'T (sim. time)';
            app.T_app = uieditfield(app.SimulationsettingsPanel, 'numeric');
            app.T_app.Limits = [0 Inf];
            app.T_app.Value = 1000;
            app.spatialdivisionsinyLabel_3 = uilabel(app.SimulationsettingsPanel);
            app.spatialdivisionsinyLabel_3.Text = '#time steps';
            app.nt_app = uieditfield(app.SimulationsettingsPanel, 'numeric');
            app.nt_app.RoundFractionalValues = 'on';
            app.nt_app.ValueDisplayFormat = '%e';
            app.nt_app.Value = 10000;
            app.spatialdivisionsinyLabel_4 = uilabel(app.SimulationsettingsPanel);
            app.spatialdivisionsinyLabel_4.Text = 'Ly (length)';
            app.Ly_app = uieditfield(app.SimulationsettingsPanel, 'numeric');
            app.Ly_app.Limits = [0 Inf];
            app.Ly_app.Value = 100;
            app.spatialdivisionsinyLabel_5 = uilabel(app.SimulationsettingsPanel);
            app.spatialdivisionsinyLabel_5.Text = '#plots during sim.';
            app.nplot_app = uieditfield(app.SimulationsettingsPanel, 'numeric');
            app.nplot_app.RoundFractionalValues = 'on';
            app.nplot_app.Value = 100;
            app.spatialdivisionsinyLabel_6 = uilabel(app.SimulationsettingsPanel);
            app.spatialdivisionsinyLabel_6.Text = 'noise';
            app.noise_app = uieditfield(app.SimulationsettingsPanel, 'numeric');
            app.noise_app.Limits = [0 Inf];
            app.noise_app.Value = 0.01;
            app.SimulationtypeSwitchLabel = uilabel(app.SimulationsettingsPanel);
            app.SimulationtypeSwitchLabel.Text = 'Simulation type';
            app.symtype_app = uiswitch(app.SimulationsettingsPanel, 'slider');
            app.symtype_app.Items = {'Lawn of cells', 'Colony growth'};
            app.symtype_app.ValueChangedFcn = createCallbackFcn(app, @symtype_appValueChanged, true);
            app.symtype_app.Value = 'Lawn of cells';
            app.ColonypropertiesPanel = uipanel(app.SimulationsettingsPanel);
            app.ColonypropertiesPanel.Enable = 'off';
            app.ColonypropertiesPanel.Title = 'Colony properties';
            app.spatialdivisionsinyLabel_7 = uilabel(app.ColonypropertiesPanel);
            app.spatialdivisionsinyLabel_7.Text = 'cmax (active zone in cell conc.)';
            app.cmax_app = uieditfield(app.ColonypropertiesPanel, 'numeric');
            app.cmax_app.Limits = [0 Inf];
            app.cmax_app.Value = 0.99;
            app.spatialdivisionsinyLabel_8 = uilabel(app.ColonypropertiesPanel);
            app.spatialdivisionsinyLabel_8.Text = 'colony width';
            app.sigma_app = uieditfield(app.ColonypropertiesPanel, 'numeric');
            app.sigma_app.Limits = [0 Inf];
            app.sigma_app.Value = 3;
            app.spatialdivisionsinyLabel_9 = uilabel(app.ColonypropertiesPanel);
            app.spatialdivisionsinyLabel_9.Text = 'c0(colony)';
            app.height_app = uieditfield(app.ColonypropertiesPanel, 'numeric');
            app.height_app.Limits = [0 Inf];
            app.height_app.Value = 1;
            app.spatialdivisionsinyLabel_10 = uilabel(app.ColonypropertiesPanel);
            app.spatialdivisionsinyLabel_10.Text = 'growth rate';
            app.growthrate = uieditfield(app.ColonypropertiesPanel, 'numeric');
            app.growthrate.Limits = [0 Inf];
            app.growthrate.Value = 0.1;
            app.spatialdivisionsinyLabel_11 = uilabel(app.ColonypropertiesPanel);
            app.spatialdivisionsinyLabel_11.Text = 'colony diff. coeff.';
            app.colony_diff = uieditfield(app.ColonypropertiesPanel, 'numeric');
            app.colony_diff.Limits = [0 Inf];
            app.colony_diff.Value = 0.01;
            app.SimulateButton = uibutton(app.SimulationsettingsPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.UpDropDownLabel = uilabel(app.SimulationsettingsPanel);
            app.UpDropDownLabel.Text = 'Up';
            app.UpDropDown = uidropdown(app.SimulationsettingsPanel);
            app.UpDropDown.Items = {'pbc', 'noflux'};
            app.UpDropDown.Value = 'pbc';
            app.DownDropDownLabel = uilabel(app.SimulationsettingsPanel);
            app.DownDropDownLabel.Text = 'Down';
            app.DownDropDown = uidropdown(app.SimulationsettingsPanel);
            app.DownDropDown.Items = {'pbc', 'noflux'};
            app.DownDropDown.Value = 'pbc';
            app.LeftDropDownLabel = uilabel(app.SimulationsettingsPanel);
            app.LeftDropDownLabel.Text = 'Left';
            app.LeftDropDown = uidropdown(app.SimulationsettingsPanel);
            app.LeftDropDown.Items = {'pbc', 'noflux'};
            app.LeftDropDown.Value = 'pbc';
            app.RightDropDownLabel = uilabel(app.SimulationsettingsPanel);
            app.RightDropDownLabel.Text = 'Right';
            app.RightDropDown = uidropdown(app.SimulationsettingsPanel);
            app.RightDropDown.Items = {'pbc', 'noflux'};
            app.RightDropDown.Value = 'pbc';
            app.PrelimSimCheckBox = uicheckbox(app.SimulationsettingsPanel);
            app.PrelimSimCheckBox.Text = 'Prelim. Sim';
            app.UITable = uitable(app.SimulationsettingsPanel);
            app.UITable.ColumnName = {'Name'; 'Diff. coef.'; 'Diffuses with colony'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true false];
            app.BoundaryconditionsLabel = uilabel(app.SimulationsettingsPanel);
            app.BoundaryconditionsLabel.Text = 'Boundary conditions';
            app.SavedataButton = uibutton(app.SimulationsettingsPanel, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Enable = 'off';
            app.SavedataButton.Text = 'Save data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spatial_simulations(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end